classdef LauncherApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        InfoButtonPushed       matlab.ui.control.Button
        PlotTimpButton         matlab.ui.control.Button
        Image2_2               matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        NumericalMethodsLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        DeschideButton         matlab.ui.control.Button
        ProblemaDropDown       matlab.ui.control.DropDown
        ProblemaDropDownLabel  matlab.ui.control.Label
        TimpAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeschideButton
        function DeschideButtonPushed(app, event)
         % Citește valoarea din dropdown corect
            selected = app.ProblemaDropDown.Value;
            switch selected
                case 'Gauss simplu'
                    gauss
                case 'Gauss cu pivotare'
                    
                    gaussPivotApp;
                case 'Chio'
                    chioApp
                case 'Doolittle'
                    doolittleApp
                case 'Seidel-Gauss'
                    seidelApp
                case 'Metoda aproximarilor succesive'
                    succesiveAprox
                case 'Newton Tangent'
                    newtonTangentApp
                case 'Integrala Poligonala'
                    polygonIntegralApp
                case 'Polinom Interpolare Newton'
                    NewtonPolynomialApp
                case 'Kyrlov'
                    kyrlovApp
                case 'Regula trapezoidala'
                    trapezoidrRuleIntegralsApp
                    
                otherwise
                    uialert(app.UIFigure, 'Metodă necunoscută', 'Eroare');
            end

        end

        % Button pushed function: PlotTimpButton
        function PlotTimpButtonPushed(app, event)
  % Lista celor 11 metode
    metode = { ...
      'Gauss simplu','Gauss cu pivotare','Chio','Doolittle',...
      'Seidel-Gauss','Metoda aproximarilor succesive','Newton Tangent',...
      'Kyrlov','Regula trapezoidala','Polinom Interpolare Newton',...
      'Integrala Poligonala'};
    timpi = zeros(size(metode));

    % Parametri de test
    R    = 500;               % repetiții pentru metode rapide
    n    = 200;  A = rand(n); b = rand(n,1);
    x0   = zeros(n,1); eps = 1e-6; itmax = 500;
    xt   = linspace(0,1,n)'; ft = sin(xt);

    for i = 1:numel(metode)
        tStart = tic;
        switch metode{i}
            case 'Gauss simplu'
                gaussSolve(A,b);

            case 'Gauss cu pivotare'
                gaussSolvePivot(A,b);

            case 'Chio'
                chioPivotalCondensation(A);

            case 'Doolittle'
                doolittleSolve(A,b);

            case 'Seidel-Gauss'
                seidelSolve(A,b,x0,eps,itmax);

            case 'Metoda aproximarilor succesive'
                g = @(x) cos(x);
                for k=1:R
                    successiveSolve(g,0,eps,itmax);
                end

            case 'Newton Tangent'
                f  = @(x) x.^3 - 2*x - 5;
                df = @(x) 3*x.^2 + 8*x;
                for k=1:R
                    newtonSolve(f, df, 1, eps, itmax);
                end

            case 'Kyrlov'
                for k=1:R
                    krylovSolve(A, rand(n,1));
                end

            case 'Regula trapezoidala'
                f_tr = @(x) interp1(xt, ft, x, 'linear');
                for k=1:R
                    trapezoidSolve(f_tr, min(xt), max(xt), eps);
                end

            case 'Polinom Interpolare Newton'
                for k=1:R
                    NewtonInterpolation(xt,ft,0.5);
                end

            case 'Integrala Poligonala'
                xv = [0 1 1 0]; yv=[0 0 1 1];
                f2 = @(x,y) x./(1+y.^2);
                for k=1:R
                    polyIntegral(xv,yv,0.5,0.5,f2);
                end
        end
        timpi(i) = toc(tStart);
    end

    % Plot cu categorical (fără spații libere)
    cla(app.TimpAxes);
    C = categorical(metode, metode);
    bar(app.TimpAxes, C, timpi);
    ylabel(app.TimpAxes,'Timp execuție [s]');
    title(app.TimpAxes,'Comparare timpi pentru cele 11 metode');
    grid(app.TimpAxes,'on');
        end

        % Button pushed function: InfoButtonPushed
        function InfoButtonPushedPushed(app, event)
              metoda = app.ProblemaDropDown.Value;
    switch metoda
        case 'Gauss simplu'
            msg = "Gauss simplu: eliminare directă fără pivotare.";
        case 'Gauss cu pivotare'
            msg = "Gauss cu pivotare: se schimbă rândurile pentru a evita pivotul zero.";
        case 'Chio'
            msg = "Chio: condensarea matricii pentru calculul determinantului.";
        case 'Doolittle'
            msg = "Doolittle: factorare A = L·R pentru rezolvarea sistemelor.";
        case 'Seidel-Gauss'
            msg = "Gauss-Seidel: iterații succesive pentru rezolvarea A·x=b.";
        case 'Metoda aproximarilor succesive'
            msg = "Aproximări succesive: x_{k+1} = g(x_k) pentru f(x)=0.";
        case 'Newton Tangent'
            msg = "Newton: x_{k+1} = x_k - f(x_k)/f'(x_k) pentru f(x)=0.";
        case 'Kyrlov'
            msg = "Krylov: extragerea coeficienților polinomului caracteristic.";
        case 'Regula trapezoidala'
            msg = "Trapezoidală: aproximarea ∫ f(x) dx segment cu segment.";
        case 'Polinom Interpolare Newton'
            msg = "Interpolare Newton: construiește polinom ul N(x) cu diferențe divizate.";
        case 'Integrala Poligonala'
            msg = "Integrală poligonală: ∬_D f(x,y)dA prin împărțirea D în triunghiuri.";
        otherwise
            msg = "Alegeți o metodă pentru a vedea explicația.";
    end
    uialert(app.UIFigure, msg, ['Despre: ' metoda]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimpAxes = uiaxes(app.UIFigure);
            title(app.TimpAxes, 'Timp')
            xlabel(app.TimpAxes, 'X')
            ylabel(app.TimpAxes, 'Y')
            zlabel(app.TimpAxes, 'Z')
            app.TimpAxes.Tag = 'TimpAxes';
            app.ProblemaDropDownLabel = uilabel(app.UIFigure);
            app.ProblemaDropDownLabel.Text = 'Problema';
            app.ProblemaDropDown = uidropdown(app.UIFigure);
            app.ProblemaDropDown.Items = {'Alege Problema', 'Gauss simplu', 'Gauss cu pivotare', 'Chio', 'Doolittle', 'Seidel-Gauss', 'Metoda aproximarilor succesive', 'Newton Tangent', 'Kyrlov', 'Regula trapezoidala', 'Polinom Interpolare Newton', 'Integrala Poligonala'};
            app.ProblemaDropDown.Tag = 'MethodDropDown';
            app.ProblemaDropDown.Value = 'Alege Problema';
            app.DeschideButton = uibutton(app.UIFigure, 'push');
            app.DeschideButton.ButtonPushedFcn = createCallbackFcn(app, @DeschideButtonPushed, true);
            app.DeschideButton.Tag = 'LaunchButton';
            app.DeschideButton.Text = 'Deschide';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'math.png');
            app.NumericalMethodsLabel = uilabel(app.UIFigure);
            app.NumericalMethodsLabel.Text = 'Numerical Methods ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Untitled.jpg');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Untitled.jpg');
            app.PlotTimpButton = uibutton(app.UIFigure, 'push');
            app.PlotTimpButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTimpButtonPushed, true);
            app.PlotTimpButton.Tag = 'PlotTimpButton';
            app.PlotTimpButton.Text = 'Timp';
            app.InfoButtonPushed = uibutton(app.UIFigure, 'push');
            app.InfoButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushedPushed, true);
            app.InfoButtonPushed.Tag = 'InfoButtonPushed';
            app.InfoButtonPushed.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LauncherApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end