classdef NewtonPolynomialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReturnButton_2      matlab.ui.control.Button
        PasidetaliatiLabel  matlab.ui.control.Label
        DDTable             matlab.ui.control.Table
        SetupButton         matlab.ui.control.Button
        StepsTable          matlab.ui.control.Table
        InfoButton          matlab.ui.control.Button
        VisualiseButton     matlab.ui.control.Button
        SolveButton         matlab.ui.control.Button
        ZEditField          matlab.ui.control.NumericEditField
        zLabel              matlab.ui.control.Label
        NEditField          matlab.ui.control.NumericEditField
        nLabel              matlab.ui.control.Label
        NodeTable           matlab.ui.control.Table
        NewtonInterpolationpolynomialLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ReturnButton   matlab.ui.control.Button 
        
       
        Property2 % Description
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % 1) Preiau n, z și nodurile
    n = app.NEditField.Value;
    z = app.ZEditField.Value;
    raw = app.NodeTable.Data(1:n+1,1:2);
    x = cell2mat(raw(:,1));   
    f = cell2mat(raw(:,2));

    % 2) Calcule: polinomul și prog data
    Nval = NewtonInterpolation(x,f,z);
    [~, prog] = NewtonInterpolationProgressData(x,f,z);

    % 3) Construi tabelul complet de diferențe divizate
    DD = DividedDifferenceTable(x,f);
    % Pregătesc numele coloanelor
    colNames = cell(1, size(DD,2));
    colNames{1} = 'x_i';
    colNames{2} = 'f[x_i]';
    for k = 1:n
        colNames{2+k} = sprintf('f[x0..x%d]', k);
    end

    % 4) Afișez tabela de diferențe
    % O fac într‑o fereastră nouă pentru claritate:
    fig2 = uifigure('Name','Divided Differences Table','Position',[200 200 600 300]);
    uit = uitable(fig2);
    uit.Data = DD;
    uit.ColumnName = colNames;
    uit.ColumnFormat = repmat({'numeric'}, 1, size(DD,2));
    uit.Position = [10 10 580 280];

    % 5) Populez StepsTable și arăt alerta
    app.StepsTable.ColumnName = prog.Properties.VariableNames;
    app.StepsTable.Data       = prog;
    uialert(app.UIFigure, sprintf('f(%g) ≈ %.6g', z, Nval), 'Result');
        
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
  
    % 1) Citește datele
    n   = app.NEditField.Value;
    raw = app.NodeTable.Data(1:n+1,1:2);
    x   = cell2mat(raw(:,1));
    f   = cell2mat(raw(:,2));
    z   = app.ZEditField.Value;

    % 2) Obține valoarea și pașii detaliați
    [Nz, prog] = NewtonInterpolationProgressData(x, f, z);

    % 3) Populează StepsTable
    app.StepsTable.ColumnName = prog.Properties.VariableNames;
    app.StepsTable.Data       = prog;

    % 4) Populează tabela diferențelor divizate
    DD = DividedDifferenceTable(x, f);
    colNames = cell(1, size(DD,2));
    colNames{1} = 'x_i'; colNames{2} = 'f[x_i]';
    for k = 1:n
        colNames{2+k} = sprintf('f[x0..x%d]', k);
    end
    app.DDTable.ColumnName = colNames;
    app.DDTable.Data       = num2cell(DD);

    % 5) Alertă cu rezultatul
    uialert(app.UIFigure, sprintf('f(%.4g) ≈ %.6g', z, Nz), 'Result');

    % 6) Plotează interpolantul și nodurile
    xx = linspace(min(x), max(x), 500);
    yy = arrayfun(@(t) NewtonInterpolation(x,f,t), xx);
    cla(app.UIAxes); hold(app.UIAxes,'on'); grid(app.UIAxes,'on');
    app.UIAxes.Color = [0.98 0.98 1];
    plot(app.UIAxes, xx, yy, 'LineWidth',2, 'DisplayName','N(x)');
    scatter(app.UIAxes, x, f, 100, 'r', 'filled', 'DisplayName','Nodes');
    plot(app.UIAxes, z, Nz, 'kp', 'MarkerSize',12, 'MarkerFaceColor','y', ...
         'DisplayName',sprintf('f(%g)=%.4g', z, Nz));
    title(app.UIAxes, sprintf('Newton Interpolation (n=%d)', n),'FontSize',14);
    xlabel(app.UIAxes,'x'); ylabel(app.UIAxes,'N(x)');
    legend(app.UIAxes,'Location','bestoutside');
    dx = max(x)-min(x);
    xlim(app.UIAxes, [min(x)-0.1*dx, max(x)+0.1*dx]);
    hold(app.UIAxes,'off');

    % 7) Formula completă în LaTeX poziționată mai jos, cu fundal galben
    coefs = DD(1,2:end);
    polyStr = '$$N(x)=';
    polyStr = [polyStr sprintf('%.3g', coefs(1))];
    for k = 2:(n+1)
        term = sprintf(' + %.3g', coefs(k));
        for j = 1:k-1
            term = [term sprintf('(x-%g)', x(j))];
        end
        polyStr = [polyStr term];
    end
    polyStr = [polyStr '$$'];
    annotation(app.UIFigure, 'textbox', ...
        [0.15 0.005 0.7 0.18], ...            % mai jos și suficient de înalt
        'Interpreter',       'latex', ...
        'String',            polyStr, ...
        'FitBoxToText',      'on', ...
        'HorizontalAlignment','center', ...
        'FontSize',          12, ...
        'Color',             [0 0 0], ...      % text negru
        'BackgroundColor',   [1 1 0.8 0.9], ...% galben deschis, 90% opac
        'EdgeColor',         'black', ...
        'Margin',            10);
        

        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
             msg = [ ...
      'Newton''s interpolation construiește polinomul:' newline newline ...
      '   N(x) = f[x_0]' newline ...
      '        + \sum_{k=1}^n f[x_0,...,x_k]\;\prod_{i=0}^{k-1}(x - x_i)' newline newline ...
      'unde diferenţele divizate f[...] se calculează recursiv.' ];
    uialert(app.UIFigure, msg, 'Method Info');
        end

        % Callback function
        function LoadExampleButtonPushed(app, event)


        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            % 1) Preia n și z
    n = app.NEditField.Value;
    z = app.ZEditField.Value;

    % 2) Redimensionează NodeTable la (n+1) rânduri și 2 coloane
    %    (xi, fi) cu NaN ca valori inițiale
    app.NodeTable.Data = num2cell(nan(n+1,2));

    % 3) Curăță StepsTable și axes
    app.StepsTable.Data = [];
    cla(app.UIAxes);

    % 4) (opțional) afișează un mesaj că a fost setat cu succes
    uialert(app.UIFigure, ...
        sprintf('Table ready for n=%d, z=%.3g',n,z), ...
        'Setup Done');


        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Axa Progres')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NewtonInterpolationpolynomialLabel = uilabel(app.UIFigure);
            app.NewtonInterpolationpolynomialLabel.Text = 'Newton Interpolation polynomial';
            app.NodeTable = uitable(app.UIFigure);
            app.NodeTable.ColumnName = {'xi'; 'fi'};
            app.NodeTable.RowName = {};
            app.NodeTable.ColumnEditable = true;
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.StepsTable = uitable(app.UIFigure);
            app.StepsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StepsTable.RowName = {};
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.DDTable = uitable(app.UIFigure);
            app.DDTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DDTable.RowName = {};
            app.PasidetaliatiLabel = uilabel(app.UIFigure);
            app.PasidetaliatiLabel.Text = 'Pasi detaliati';
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonPolynomialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end