classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SetMatrixSizeButton       matlab.ui.control.Button
        ProgressTable             matlab.ui.control.Table
        CoeffsTable               matlab.ui.control.Table
        InfoButton                matlab.ui.control.Button
        VisualiseButton           matlab.ui.control.Button
        SolveButton               matlab.ui.control.Button
        UITableY0                 matlab.ui.control.NumericEditField
        y0Label                   matlab.ui.control.Label
        MatrixSizeField           matlab.ui.control.NumericEditField
        DimensiuneamatriceiLabel  matlab.ui.control.Label
        UITableA                  matlab.ui.control.Table
        KrylovsmethodfortodeterminethecoefficientsofcharacteristicpolynomialLabel  matlab.ui.control.Label
        ProgressAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
             try
            % Citește A și y0
            A  = cell2mat(app.UITableA.Data);
            y0 = cell2mat(app.UITableY0.Data);
            % Rulare metoda Krylov
            c = krylovSolve(A, y0);
            % Populează tabelul coeficienți
            n = numel(c);
            names = arrayfun(@(k) sprintf('c%d',k),1:n,'uni',0);
            app.CoeffsTable.ColumnName = names;
            app.CoeffsTable.Data       = num2cell(c.');
        catch ME
            uialert(app.UIFigure, ME.message, 'Eroare Solve');
             end
        
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
              try
            % Citește A și y0
            A  = cell2mat(app.UITableA.Data);
            y0 = cell2mat(app.UITableY0.Data);
            % Coletează progres (condiția matricei B)
            [iters, conds] = krylovProgressData(A, y0);
            % Tabel progres
            app.ProgressTable.ColumnName = {'Iter','cond(B)'};
            app.ProgressTable.Data       = [num2cell(iters.'), num2cell(conds.')];
            % Grafic
            cla(app.ProgressAxes);
            plot(app.ProgressAxes, iters, conds, '-o','LineWidth',1.5);
            xlabel(app.ProgressAxes,'Încercare');
            ylabel(app.ProgressAxes,'cond(B)');
            title(app.ProgressAxes,'Calitatea matricei B');
            grid(app.ProgressAxes,'on');
        catch ME
            uialert(app.UIFigure, ME.message, 'Eroare Vizualizare');
              end
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
             msg = [ ...
          "Metoda Krylov determină coeficienții polinomului caracteristic p_A(λ).", newline,...
          "1) Construiește y^(k)=A·y^(k-1) pentru k=1..n", newline,...
          "2) Formați B=[y^(n) … y^(1)] și rhs=−y^(n+1)", newline,...
          "3) Rezolvați B·c = rhs → c = [c1…cn]", newline,...
          "4) p_A(λ)= λ^n + c1 λ^(n-1)+…+cn", newline,...
          "Dacă B nu e inversabilă, alegeți alt y^(0) și reapelați." ];
        uialert(app.UIFigure, strjoin(msg, newline), 'Despre Krylov');
        end

        % Button pushed function: SetMatrixSizeButton
        function SetMatrixSizeButtonPushed(app, event)
             n = app.MatrixSizeField.Value;

    % Update matrix A table
    app.UITableA.Data = zeros(n, n);
    app.UITableA.ColumnName = strcat("c", string(1:n));
    app.UITableA.RowName = strcat("r", string(1:n));

    % Update y0 table
    app.UITableY0.Data = zeros(n, 1);
    app.UITableY0.ColumnName = "y₀";
    app.UITableY0.RowName = strcat("r", string(1:n));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgressAxes = uiaxes(app.UIFigure);
            title(app.ProgressAxes, 'Progress Axes')
            xlabel(app.ProgressAxes, 'X')
            ylabel(app.ProgressAxes, 'Y')
            zlabel(app.ProgressAxes, 'Z')
            app.KrylovsmethodfortodeterminethecoefficientsofcharacteristicpolynomialLabel = uilabel(app.UIFigure);
            app.KrylovsmethodfortodeterminethecoefficientsofcharacteristicpolynomialLabel.Text = 'Krylov’s method for to determine the coefficients of characteristic polynomial';
            app.UITableA = uitable(app.UIFigure);
            app.UITableA.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableA.RowName = {};
            app.DimensiuneamatriceiLabel = uilabel(app.UIFigure);
            app.DimensiuneamatriceiLabel.Text = 'Dimensiunea matricei';
            app.MatrixSizeField = uieditfield(app.UIFigure, 'numeric');
            app.y0Label = uilabel(app.UIFigure);
            app.y0Label.Text = 'y0';
            app.UITableY0 = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.CoeffsTable = uitable(app.UIFigure);
            app.CoeffsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CoeffsTable.RowName = {};
            app.ProgressTable = uitable(app.UIFigure);
            app.ProgressTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProgressTable.RowName = {};
            app.ProgressTable.ColumnEditable = true;
            app.SetMatrixSizeButton = uibutton(app.UIFigure, 'push');
            app.SetMatrixSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetMatrixSizeButtonPushed, true);
            app.SetMatrixSizeButton.Text = 'SetMatrixSize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end