classdef chioApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReturnButton_2                matlab.ui.control.Button
        SpeedSlider                   matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        PasiiProblemeiLabel           matlab.ui.control.Label
        MatriceLabel                  matlab.ui.control.Label
        InfoButton                    matlab.ui.control.Button
        ChioPivotalCondensationLabel  matlab.ui.control.Label
        StepsTableChio                matlab.ui.control.Table
        VizButton                     matlab.ui.control.Button
        DetField                      matlab.ui.control.NumericEditField
        DeterminantEditFieldLabel     matlab.ui.control.Label
        CalculeazaButton              matlab.ui.control.Button
        UITableA                      matlab.ui.control.Table
        DimensiuneButton              matlab.ui.control.Button
        nField                        matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        ProgressAxes                  matlab.ui.control.UIAxes
        ProgressAxesChio              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
            ChioData   % struct cu pivots, sizes, updates, AcurList, n
            ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DimensiuneButton
        function DimensiuneButtonPushed(app, event)
             n = round(app.nField.Value);
    % redimensionează tabelă la n×n
    app.UITableA.ColumnName    = repmat({''},1,n);
    app.UITableA.Data          = num2cell(zeros(n,n));
    % golește câmpul determinant
    app.DetField.Value         = [];
        end

        % Button pushed function: CalculeazaButton
        function CalculeazaButtonPushed(app, event)
            % citește A
    rawA = app.UITableA.Data;
    if iscell(rawA)
        A = cell2mat(rawA);
    else
        A = rawA;
    end

    try
        detA = chioPivotalCondensation(A);
        app.DetField.Value = detA;
    catch ME
        uialert(app.UIFigure, ME.message, 'Eroare Chio');
    end

        end

        % Button pushed function: VizButton
        function VizButtonPushed(app, event)
   try
            % --- Citește matricea A ---
            rawA = app.UITableA.Data;
            if iscell(rawA), A = cell2mat(rawA); else A = rawA; end

            % --- Obține pivots și sizes din helper ---
            [pivots, sizes] = chioProgressData(A);
            n = numel(pivots);

            % --- Reconstruiește pas cu pas submatrici și updates ---
            Mcur      = A;              % matricea curentă
            updates   = {};
            AcurList  = cell(n,1);
            stepIdx   = 1;
            signDet   = 1;
            while true
                N = size(Mcur,1);
                AcurList{stepIdx} = Mcur;

                % Pivot = A(1,1)
                p = Mcur(1,1);
                updates(end+1,:) = {stepIdx, p, N, 'a_{11}', 'pivot', p};

                % Swap dacă pivot=0
                if p==0 && N>1
                    lin = find(Mcur(2:end,1)~=0,1)+1;
                    if isempty(lin), break; end
                    Mcur([1 lin],:) = Mcur([lin 1],:);
                    signDet = -signDet;
                    updates(end+1,:) = {stepIdx, p, N, ...
                        sprintf('swap 1↔%d',lin),'swap rânduri',NaN};
                    p = Mcur(1,1);
                end

                % Condensare Chio
                if N>1
                    B = zeros(N-1);
                    for i=2:N
                      for j=2:N
                        val = p*Mcur(i,j) - Mcur(i,1)*Mcur(1,j);
                        updates(end+1,:) = {stepIdx, p, N, ...
                            sprintf('B_{%d%d}',i-1,j-1), ...
                            sprintf('%.4g·%.4g - %.4g·%.4g',p,Mcur(i,j),Mcur(i,1),Mcur(1,j)), val};
                        B(i-1,j-1) = val;
                      end
                    end
                    Mcur = B;
                    stepIdx = stepIdx+1;
                else
                    % ultimul pivot
                    updates(end+1,:) = {stepIdx, p, 1, 'a_{11}', 'pivot final', p};
                    break;
                end
            end

            % --- Salvează în ChioData ---
            app.ChioData.pivots   = pivots;
            app.ChioData.sizes    = sizes;
            app.ChioData.updates  = updates;
            app.ChioData.AcurList = AcurList;
            app.ChioData.n        = stepIdx;

            % --- Configurează slider ---
            app.SpeedSlider.Limits       = [1, stepIdx];
            app.SpeedSlider.MajorTicks   = 1:stepIdx;
            app.SpeedSlider.Value        = 1;
            app.SpeedSlider.ValueChangedFcn = @(s,e) chioSliderChanged(app,e);

            % --- Redesenăm primul pas ---
            renderChioStep(app,1);

        catch ME
            fig = ancestor(app.UITableA,'figure');
            uialert(fig, ME.message, 'Eroare Vizualizare');
        end
    end

    % 2) Funcție care redesenează pasul k
    function renderChioStep(app, k)
        pivots   = app.ChioData.pivots;
        sizes    = app.ChioData.sizes;
        updates  = app.ChioData.updates;
        AcurList = app.ChioData.AcurList;

        % --- Heat‑map pe primul axes ---
        cla(app.ProgressAxes);
        M = AcurList{k};
        imagesc(app.ProgressAxes, M);
        colormap(app.ProgressAxes,'parula');
        axis(app.ProgressAxes,'equal','tight');
        title(app.ProgressAxes,sprintf('Pas %d: pivot = %.4g',k,pivots(k)), ...
              'FontWeight','bold','Color','blue');
        colorbar(app.ProgressAxes);
        rectangle(app.ProgressAxes,'Position',[1-0.5,1-0.5,1,1], ...
                  'EdgeColor','red','LineWidth',2);
        [nr,nc] = size(M);
        for i=1:nr
            for j=1:nc
                text(app.ProgressAxes,j,i,sprintf('%.2f',M(i,j)), ...
                     'HorizontalAlignment','center','Color','white','FontWeight','bold');
            end
        end

        % --- Grafic progres pe ProgressAxesChio ---
        cla(app.ProgressAxesChio);
        steps = 1:numel(pivots);
        yyaxis(app.ProgressAxesChio,'left');
            plot(app.ProgressAxesChio, steps, pivots, '-o','LineWidth',1.5,'MarkerEdgeColor','blue');
            hold(app.ProgressAxesChio,'on');
            plot(app.ProgressAxesChio, k, pivots(k), 'o','MarkerSize',12,'LineWidth',2,'MarkerEdgeColor','red');
            hold(app.ProgressAxesChio,'off');
            ylabel(app.ProgressAxesChio,'Pivot A_{11}');
        yyaxis(app.ProgressAxesChio,'right');
            plot(app.ProgressAxesChio, steps, sizes, '-s','LineWidth',1.5,'MarkerEdgeColor','green');
            ylabel(app.ProgressAxesChio,'Dimensiune sub‑matrice');
        xlabel(app.ProgressAxesChio,'Pas Chio');
        title(app.ProgressAxesChio,'Progres Chio','FontWeight','bold');
        legend(app.ProgressAxesChio,{'Pivot','Pivot curent','Dimensiune'},'Location','best');
        grid(app.ProgressAxesChio,'on');

        % --- Tabel detaliat ---
        sel = cell2mat(updates(:,1)) <= k;
        app.StepsTableChio.ColumnName = {'Pas','Pivot','Dimensiune','Element','Formulă','Valoare'};
        app.StepsTableChio.Data = updates(sel,:);
    end

    % 3) Callback slider
    function chioSliderChanged(app, event)
        k = round(app.SpeedSlider.Value);
        renderChioStep(app, k);
    

        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)

    msg = sprintf([ ...
        '🔸 Metoda Chio calculează determinantul prin condensare:\n' ...
        '- La fiecare pas, se construiește o sub-matrice nouă.\n' ...
        '- Se folosește formula Chio pentru B(i,j):\n\n' ...
        '    B(i,j) = A(i+1,j+1)*A(1,1) - A(i+1,1)*A(1,j+1)\n\n' ...
        '- Se aplică recursiv până rămâne un scalar.\n' ...
        '- Dacă A(1,1) = 0, se face swap cu o linie validă.\n\n' ...
        '🔹 Formula finală:\n' ...
        '    det(A) = A(1,1)^(n-2) * det(B) / factor\n\n' ...
        '🔹 Eficientă pentru matrici mari și sparse.\n']);
    uialert(app.UIFigure, msg, 'Metoda Chio');

        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'VizButton';
            app.ProgressAxesChio = uiaxes(app.UIFigure);
            title(app.ProgressAxesChio, 'Evolutie pivot')
            xlabel(app.ProgressAxesChio, 'X')
            ylabel(app.ProgressAxesChio, 'Y')
            zlabel(app.ProgressAxesChio, 'Z')
            app.ProgressAxesChio.Tag = 'ProgressAxesChio';
            app.ProgressAxes = uiaxes(app.UIFigure);
            title(app.ProgressAxes, 'Title')
            xlabel(app.ProgressAxes, 'X')
            ylabel(app.ProgressAxes, 'Y')
            zlabel(app.ProgressAxes, 'Z')
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n=';
            app.nField = uieditfield(app.UIFigure, 'numeric');
            app.nField.AllowEmpty = 'on';
            app.nField.Tag = 'nField';
            app.nField.Value = 3;
            app.DimensiuneButton = uibutton(app.UIFigure, 'push');
            app.DimensiuneButton.ButtonPushedFcn = createCallbackFcn(app, @DimensiuneButtonPushed, true);
            app.DimensiuneButton.Tag = 'SetSizeButton';
            app.DimensiuneButton.Text = 'Dimensiune';
            app.UITableA = uitable(app.UIFigure);
            app.UITableA.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.CalculeazaButton.Tag = 'SolveButton';
            app.CalculeazaButton.Text = 'Calculeaza';
            app.DeterminantEditFieldLabel = uilabel(app.UIFigure);
            app.DeterminantEditFieldLabel.Text = 'Determinant =';
            app.DetField = uieditfield(app.UIFigure, 'numeric');
            app.DetField.AllowEmpty = 'on';
            app.DetField.Tag = 'DetField';
            app.VizButton = uibutton(app.UIFigure, 'push');
            app.VizButton.ButtonPushedFcn = createCallbackFcn(app, @VizButtonPushed, true);
            app.VizButton.Text = 'Vizualizeaza';
            app.StepsTableChio = uitable(app.UIFigure);
            app.StepsTableChio.ColumnName = {'Pas'; 'Pivot'; 'Dimensiune'};
            app.StepsTableChio.RowName = {};
            app.StepsTableChio.Tag = 'StepsTableChio';
            app.ChioPivotalCondensationLabel = uilabel(app.UIFigure);
            app.ChioPivotalCondensationLabel.Text = 'Chio Pivotal Condensation';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tag = 'InfoButton';
            app.InfoButton.Text = 'InfoButton';
            app.MatriceLabel = uilabel(app.UIFigure);
            app.MatriceLabel.Text = 'Matrice';
            app.PasiiProblemeiLabel = uilabel(app.UIFigure);
            app.PasiiProblemeiLabel.Text = 'Pasii Problemei';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.SpeedSlider = uislider(app.UIFigure);
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chioApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end