classdef doolittleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReturnButton_2             matlab.ui.control.Button
        SpeedSlider                matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        PasidetaliatiLabel         matlab.ui.control.Label
        MatriceaRLabel             matlab.ui.control.Label
        MatriceaLLabel             matlab.ui.control.Label
        InfoButton                 matlab.ui.control.Button
        VizualizeazaButton         matlab.ui.control.Button
        StepsTableDoolittle        matlab.ui.control.Table
        UITableX                   matlab.ui.control.Table
        DetField                   matlab.ui.control.NumericEditField
        DeterminantEditFieldLabel  matlab.ui.control.Label
        UITableU                   matlab.ui.control.Table
        UITableL                   matlab.ui.control.Table
        SolveButton                matlab.ui.control.Button
        UITableB                   matlab.ui.control.Table
        UITableA                   matlab.ui.control.Table
        SetSizeButton              matlab.ui.control.Button
        nField                     matlab.ui.control.NumericEditField
        nEditFieldLabel            matlab.ui.control.Label
        DoolittleLabel             matlab.ui.control.Label
        MatrixAxes                 matlab.ui.control.UIAxes
        ProgressAxesDoolittle      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        L
        U
        Lmatrix   % pentru a păstra L între butoane
Umatrix   % pentru a păstra U între butoane
DoolittleData  
        Property6 % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetSizeButton
        function SetSizeButtonPushed(app, event)
  n = round(app.nField.Value);

    % A: n×n
    app.UITableA.Data           = num2cell(zeros(n,n));
    app.UITableA.ColumnName     = repmat({''},1,n);
    app.UITableA.ColumnEditable = true(1,n);

    % b: n×1
    app.UITableB.Data           = num2cell(zeros(n,1));
    app.UITableB.ColumnName     = {'b'};
    app.UITableB.ColumnEditable = true;

    % golește rezultate
    app.UITableL.Data = {};
    app.UITableU.Data = {};
    app.UITableX.Data = {};
    app.DetField.Value = 0;

    cla(app.ProgressAxesDoolittle);
    app.StepsTableDoolittle.Data = {};
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
                  rawA = app.UITableA.Data;
    rawb = app.UITableB.Data;

    if iscell(rawA)
        A = cell2mat(rawA);
    else
        A = rawA;
    end

    if iscell(rawb)
        b = cell2mat(rawb);
    else
        b = rawb;
    end
    b = b(:);

    [ra, ca] = size(A);
    if ra ~= ca
        uialert(app.UIFigure, 'Matricea A nu este pătratică.', 'Eroare');
        return;
    end
    if numel(b) ~= ra
        uialert(app.UIFigure, sprintf('Vectorul b trebuie să aibă %d elemente.', ra), 'Eroare');
        return;
    end

    try
        [~, ~, ~, x, detA] = doolittleSolve(A, b);
    catch ME
        uialert(app.UIFigure, ME.message, 'Eroare la Doolittle');
        return;
    end

    % Afișare rezultate
    app.UITableL.Data = num2cell(app.L);
    app.UITableU.Data = num2cell(app.U);
    app.UITableX.Data = num2cell(x);
    app.DetField.Value = detA;

    % Salvare în proprietățile aplicației
    app.Lmatrix = app.L;
    app.Umatrix = app.U;
        end

        % Button pushed function: VizualizeazaButton
        function VizualizeazaButtonPushed(app, event)
   try
        % 1) Citește A și b
        rawA = app.UITableA.Data;
        if iscell(rawA)
            A = cell2mat(rawA);
        else
            A = rawA;
        end

        rawb = app.UITableB.Data;
        if iscell(rawb)
            b = cell2mat(rawb);
        else
            b = rawb;
        end
        b = b(:);

        n = size(A,1);
        if n~=size(A,2) || numel(b)~=n
            error('Matricea A trebuie pătratică și b de dimensiune n.');
        end

        % 2) Obține pivotii (din funcție) și forțează sizes
        pivots = doolittleProgressData(A);    % vector pivotilor A(k,k)
        sizes  = (n:-1:1)';                   % [n, n-1,...,1]'

        % 3) Rulează Doolittle pas cu pas
        AcurList = cell(n,1);
        updates  = {};
        L = eye(n);
        U = zeros(n);
        for k = 1:n
            AcurList{k} = A;
            % U(k,j)
            for j = k:n
                if k>1
                    val = A(k,j) - L(k,1:k-1)*U(1:k-1,j);
                else
                    val = A(k,j);
                end
                U(k,j) = val;
                updates(end+1,:) = {k, U(k,k), sizes(k), sprintf('U_{%d%d}',k,j), '', val};
            end
            % L(i,k)
            for i = k+1:n
                if k>1
                    val = (A(i,k)-L(i,1:k-1)*U(1:k-1,k))/U(k,k);
                else
                    val = A(i,k)/U(k,k);
                end
                L(i,k) = val;
                updates(end+1,:) = {k, U(k,k), sizes(k), sprintf('L_{%d%d}',i,k), '', val};
            end
            % Actualizează A
            A = L*U;
        end

        % 4) Salvează în app și afișează L și U în tabele
        app.DoolittleData.pivots   = pivots(:);
        app.DoolittleData.sizes    = sizes;
        app.DoolittleData.updates  = updates;
        app.DoolittleData.AcurList = AcurList;
        app.DoolittleData.nsteps   = n;
        app.Lmatrix = L;
        app.Umatrix = U;

        % *** Afișăm matricile L și U ***
        app.UITableL.Data = num2cell(app.Lmatrix);
        app.UITableU.Data = num2cell(app.Umatrix);

        % 5) Configurează slider‑ul
        app.SpeedSlider.Limits        = [1 n];
        app.SpeedSlider.MajorTicks    = 1:n;
        app.SpeedSlider.Value         = 1;
        app.SpeedSlider.ValueChangedFcn = @(s,e) renderDoolittleStep(app, round(s.Value));

        % 6) Desenează pasul 1
        renderDoolittleStep(app,1);

    catch ME
        uialert(app.UIFigure, ME.message, 'Eroare Vizualizare');
    end

    % ——— Funcție locală: desenează pasul k ———
    function renderDoolittleStep(app, k)
        D        = app.DoolittleData;
        pivots   = D.pivots;
        sizes    = D.sizes;
        updates  = D.updates;
        AcurList = D.AcurList;
        nsteps   = D.nsteps;

        % (1) Heatmap
        M = AcurList{k};
        cla(app.MatrixAxes);
        imagesc(app.MatrixAxes, M);
        colormap(app.MatrixAxes,'parula');
        axis(app.MatrixAxes,'equal','tight');
        colorbar(app.MatrixAxes);
        title(app.MatrixAxes,sprintf('Pas %d',k),'FontWeight','bold');
        rectangle(app.MatrixAxes,'Position',[k-0.5,k-0.5,1,1], ...
                  'EdgeColor','r','LineWidth',2);
        [nr,nc] = size(M);
        for ii = 1:nr
            for jj = 1:nc
                text(app.MatrixAxes,jj,ii,sprintf('%.2f',M(ii,jj)), ...
                     'HorizontalAlignment','center','Color','w','FontWeight','bold');
            end
        end

        % (2) Pivot & Dimensiune
        steps = 1:nsteps;
        cla(app.ProgressAxesDoolittle);
        yyaxis(app.ProgressAxesDoolittle,'left');
        plot(app.ProgressAxesDoolittle,steps,pivots,'-o','LineWidth',1.5);
        hold(app.ProgressAxesDoolittle,'on');
        plot(app.ProgressAxesDoolittle,k,pivots(k),'sr','MarkerSize',8);
        ylabel(app.ProgressAxesDoolittle,'Pivot U_{kk}');
        yyaxis(app.ProgressAxesDoolittle,'right');
        plot(app.ProgressAxesDoolittle,steps,sizes,'-s','LineWidth',1.5);
        ylabel(app.ProgressAxesDoolittle,'Dimensiune sub‑sistem');
        xlabel(app.ProgressAxesDoolittle,'Pas Doolittle');
        title(app.ProgressAxesDoolittle,'Evoluție pivot & sub‑sistem','FontWeight','bold');
        legend(app.ProgressAxesDoolittle,{'Pivot','Curent','Dimensiune'},'Location','best');
        grid(app.ProgressAxesDoolittle,'on');
        hold(app.ProgressAxesDoolittle,'off');

        % (3) Tabel detaliat
        sel = cell2mat(updates(:,1)) <= k;
        app.StepsTableDoolittle.ColumnName = {'Pas','Pivot','Dimensiune','Element','Formula','Valoare'};
        app.StepsTableDoolittle.Data       = updates(sel,:);
    end


        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
             % Găsește fereastra parent
    parentFig = ancestor(app.InfoButton, 'figure');

    % Mesaj explicativ
    msg = sprintf([ ...
        '🔸 Metoda Doolittle (LU cu pivotare parțială)\n\n' ...
        'Scop: Descompune A în L·U (L lower cu 1 pe diagonală, U upper),\n' ...
        'cu permutări P pentru stabilitate numerică:\n' ...
        '    P·A = L·U\n\n' ...
        'Formule cheie:\n' ...
        '  U(k,j) = A(k,j) - Σ_{h=1}^{k-1} L(k,h)*U(h,j),   j = k..n\n' ...
        '  L(i,k) = (A(i,k) - Σ_{h=1}^{k-1} L(i,h)*U(h,k)) / U(k,k),   i = k+1..n\n\n' ...
        'După ce ai L și U, rezolvi sistemul:\n' ...
        '  L·y = P·b   (forward substitution)\n' ...
        '  U·x = y     (backward substitution)\n\n' ...
        'Determinant:\n' ...
        '  det(A) = det(P)·∏_{k=1}^n U(k,k)\n\n' ...
        'Avantaje:\n' ...
        '- O(n^3) ca și Gauss\n' ...
        '- Pivotarea asigură stabilitate numerică\n' ...
        '- Permite reutilizarea L și U pentru mai mulți b\n' ...
        ]);
    
    % Afișează mesajul
    uialert(parentFig, msg, 'Metoda Doolittle');
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgressAxesDoolittle = uiaxes(app.UIFigure);
            title(app.ProgressAxesDoolittle, 'Axa progres')
            xlabel(app.ProgressAxesDoolittle, 'X')
            ylabel(app.ProgressAxesDoolittle, 'Y')
            zlabel(app.ProgressAxesDoolittle, 'Z')
            app.MatrixAxes = uiaxes(app.UIFigure);
            title(app.MatrixAxes, 'Title')
            xlabel(app.MatrixAxes, 'X')
            ylabel(app.MatrixAxes, 'Y')
            zlabel(app.MatrixAxes, 'Z')
            app.DoolittleLabel = uilabel(app.UIFigure);
            app.DoolittleLabel.Text = 'Doolittle';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n=';
            app.nField = uieditfield(app.UIFigure, 'numeric');
            app.nField.Tag = 'nField';
            app.SetSizeButton = uibutton(app.UIFigure, 'push');
            app.SetSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetSizeButtonPushed, true);
            app.SetSizeButton.Tag = 'SetSizeButton';
            app.SetSizeButton.Text = 'Text size';
            app.UITableA = uitable(app.UIFigure);
            app.UITableA.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.UITableB = uitable(app.UIFigure);
            app.UITableB.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Tag = 'SolveButton';
            app.SolveButton.Text = 'SolveButton';
            app.UITableL = uitable(app.UIFigure);
            app.UITableL.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableL.RowName = {};
            app.UITableU = uitable(app.UIFigure);
            app.UITableU.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableU.RowName = {};
            app.DeterminantEditFieldLabel = uilabel(app.UIFigure);
            app.DeterminantEditFieldLabel.Text = 'Determinant';
            app.DetField = uieditfield(app.UIFigure, 'numeric');
            app.DetField.Tag = 'DetField';
            app.UITableX = uitable(app.UIFigure);
            app.UITableX.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableX.RowName = {};
            app.StepsTableDoolittle = uitable(app.UIFigure);
            app.StepsTableDoolittle.ColumnName = {'Pas'; 'Pivot'; 'Dimensiune'};
            app.StepsTableDoolittle.RowName = {};
            app.VizualizeazaButton = uibutton(app.UIFigure, 'push');
            app.VizualizeazaButton.ButtonPushedFcn = createCallbackFcn(app, @VizualizeazaButtonPushed, true);
            app.VizualizeazaButton.Tag = 'VizButton';
            app.VizualizeazaButton.Text = 'Vizualizeaza';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tag = 'InfoButton';
            app.InfoButton.Text = 'Info';
            app.MatriceaLLabel = uilabel(app.UIFigure);
            app.MatriceaLLabel.Text = 'Matricea L';
            app.MatriceaRLabel = uilabel(app.UIFigure);
            app.MatriceaRLabel.Text = 'Matricea R';
            app.PasidetaliatiLabel = uilabel(app.UIFigure);
            app.PasidetaliatiLabel.Text = 'Pasi detaliati';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.SpeedSlider = uislider(app.UIFigure);
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doolittleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end