classdef gauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GausApp                  matlab.ui.Figure
        ReturnButton_2           matlab.ui.control.Button
        SpeedSlider              matlab.ui.control.Slider
        VitezaanimatieLabel      matlab.ui.control.Label
        LabelA                   matlab.ui.control.Label
        PasiiProblemei           matlab.ui.control.Label
        InfoButton               matlab.ui.control.Button
        VizualizeazapasiiButton  matlab.ui.control.Button
        StepsTableGauss          matlab.ui.control.Table
        Gauss                    matlab.ui.control.Label
        SetSizeButton            matlab.ui.control.Button
        NField                   matlab.ui.control.NumericEditField
        DimensiuneLabel          matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        UITableB                 matlab.ui.control.Table
        SolveButton              matlab.ui.control.Button
        UITableX                 matlab.ui.control.Table
        MatrixAxes               matlab.ui.control.UIAxes
        ProgressAxesGauss        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    LinePivot    matlab.graphics.chart.primitive.Line
    LineSize     matlab.graphics.chart.primitive.Line
        GaussData  % vom pune pivots, sizes, updates, AcurList, n aici
        Property4 % Description
    ReturnButton   matlab.ui.control.Button   % aici        Property5 % Description
        Property7 % Description
    end


    methods (Access = private)
        
        function results = func(app)
            % 1) Calculează și stochează datele de progres la apelul "Visualise"
function VisualiseButtonPushed(app, event)
    % ————— 1. Citește A, b și build updates, pivots, sizes —————
    A0 = cell2mat(app.UITable.Data);
    b0 = cell2mat(app.UITableB.Data);
    b0 = b0(:);
    [pivots, sizes] = gaussProgressData(A0(:,1:end-1), A0(:,end));
    
    % ————— 2. Construiește history-ul de "updates" (ca mai înainte) —————
    updates = {};
    Acur = [A0(:,1:end-1), b0];
    n   = size(A0,1);
    for k = 1:n-1
        % păstrează Acur în fiecare pas într‑o listă separată
        AcurList{k} = Acur;  
        % (codul tău de eliminare care schimbă Acur) …
        % aici e foarte util să fii scris deja updates{k,:} și noua Acur
        % … actualizezi AcurList{k+1} …
    end
    % persisti GaussData
    app.GaussData.pivots  = pivots;
    app.GaussData.sizes   = sizes;
    app.GaussData.updates = updates;
    app.GaussData.AcurList= AcurList;
    app.GaussData.n       = n;

    % ————— 3. Configurează slider-ul —————
    app.SpeedSlider.Limits       = [1, n-1];
    app.SpeedSlider.MajorTicks   = 1:n-1;
    app.SpeedSlider.Value        = 1;
    app.SpeedSlider.ValueChangedFcn = @(s,e) SpeedSliderValueChanged(app,e);

    % ————— 4. Desenează pasul 1 —————
    renderGaussStep(app,1);
end

% ----------------------------------------------------------------------------
% 2) Render pentru un pas k: redraw heatmap, grafic și tabel
function renderGaussStep(app, k)
    pivots  = app.GaussData.pivots;
    sizes   = app.GaussData.sizes;
    updates = app.GaussData.updates;
    AcurList= app.GaussData.AcurList;
    
    % —– 1) Heat‑map în MatrixAxes —–
    cla(app.MatrixAxes);
    M = AcurList{k};
    imagesc(app.MatrixAxes, M);
    colormap(app.MatrixAxes,'hot');
    axis(app.MatrixAxes,'equal','tight');
    colorbar(app.MatrixAxes);
    title(app.MatrixAxes, sprintf('Pas %d: Acurent', k));
    
    % —– 2) Grafic pivots vs sizes —–
    cla(app.ProgressAxesGauss);
    steps = 1:numel(pivots);
    yyaxis(app.ProgressAxesGauss,'left');
      plot(app.ProgressAxesGauss, steps(1:k), pivots(1:k), '-o','LineWidth',1.5);
      ylabel(app.ProgressAxesGauss,'Pivot a_{kk}');
    yyaxis(app.ProgressAxesGauss,'right');
      plot(app.ProgressAxesGauss, steps(1:k), sizes(1:k), '-s','LineWidth',1.5);
      ylabel(app.ProgressAxesGauss,'Dimensiune sub-sistem');
    xlabel(app.ProgressAxesGauss,'Pas');
    title(app.ProgressAxesGauss,'Evoluția pivots și dimensiunii');
    legend(app.ProgressAxesGauss,{'Pivot','Dimensiune'},'Location','best');
    grid(app.ProgressAxesGauss,'on');
    
    % —– 3) Tabel cu primele k rânduri —–
    app.StepsTableGauss.ColumnName = {'Pas','Pivot','Dimensiune','Element','Formulă','Valoare'};
    app.StepsTableGauss.Data       = updates(1:k,:);
end

% ----------------------------------------------------------------------------
% 3) Callback slider → afișează pasul selectat
function SpeedSliderValueChanged(app, event)
    k = round(app.SpeedSlider.Value);
    renderGaussStep(app, k);
end

            
        end
      
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
       % 1) Ia datele brute din tabele
rawA = app.UITable.Data;     
rawb = app.UITableB.Data;    

% 2) Convertește la matrici numerice
if iscell(rawA), A = cell2mat(rawA);
    
else
    A = rawA; 
end
if iscell(rawb), b = cell2mat(rawb); 
else 
    b = rawb;
end
b = b(:);  % vector coloană

try
    % 3) Rezolvă și afișează
    x = gaussSolve(A, b);
    app.UITableX.Data = num2cell(x);
catch ME
    % în loc de app.UIFigure sau app.gauss
            fig = ancestor(app.MatrixAxes, 'figure');
            uialert(fig, ME.message, 'Eroare la Visualise');
end

        end

        % Button pushed function: SetSizeButton
        function SetSizeButtonPushed(app, event)
             n = round(app.NField.Value);           % ia dimensiunea
    % 1) Setează tabelele pentru A și b
    % A va fi n×n, b va fi n×1
    app.UITable.ColumnName = repmat({''}, 1, n);
    app.UITable.Data       = num2cell(zeros(n,n));
    
    app.UITableB.ColumnName = {'b'};
    app.UITableB.Data       = num2cell(zeros(n,1));
    
    % 2) Şterge şi UITableX (în caz că avea vechi date)
    app.UITableX.Data = {};    
        end

        % Button pushed function: VizualizeazapasiiButton
        function VizualizeazapasiiButtonPushed(app, event)
 try
                % 1. Citește A și b
                rawA  = app.UITable.Data;
                rawb = app.UITableB.Data;
                if iscell(rawA),  A = cell2mat(rawA);  else A = rawA;  end
                if iscell(rawb), b = cell2mat(rawb); else b = rawb; end
                b = b(:);
                n = size(A,1);

                % 2. Calculează pivots și sizes
                [pivots, sizes] = gaussProgressData(A, b);

                % 3. Construcție updates & AcurList
                Acur     = [A, b];
                updates  = {};
                AcurList = cell(n-1,1);
                for k = 1:n-1
                    % salvează stare
                    AcurList{k} = Acur;

                    % linia pivot
                    updates(end+1,:) = { ...
                        k, pivots(k), sizes(k), ...
                        sprintf('a_{%d%d}',k,k), ...
                        sprintf('pivot = A(%d,%d)',k,k), pivots(k)};

                    % eliminare Gauss (fără animație)
                    a11 = Acur(k,k);
                    for i = k+1:n
                        factor = Acur(i,k)/a11;
                        for j = k+1:n+1
                            old       = Acur(i,j);
                            Acur(i,j) = old - factor*Acur(k,j);
                            updates(end+1,:) = { ...
                                k, pivots(k), sizes(k), ...
                                sprintf('a_{%d%d}',i,j), ...
                                sprintf('(%.4g·%.4g - %.4g·%.4g)/%.4g', ...
                                        a11, old, Acur(i,k), Acur(k,j), a11), ...
                                Acur(i,j)};
                        end
                        Acur(i,k) = 0;
                    end
                end

                % 4. Stocare în GaussData
                app.GaussData.pivots   = pivots;
                app.GaussData.sizes    = sizes;
                app.GaussData.updates  = updates;
                app.GaussData.AcurList = AcurList;
                app.GaussData.n        = n;

                % 5. Configurare slider pentru pași
                app.SpeedSlider.Limits       = [1, n-1];
                app.SpeedSlider.MajorTicks   = 1:(n-1);
                app.SpeedSlider.Value        = 1;
                app.SpeedSlider.ValueChangedFcn = @(s,e) SpeedSliderValueChanged(app,e);

                % 6. Redesenăm pasul 1
                renderGaussStep(app, 1);

            catch ME
                fig = ancestor(app.MatrixAxes, 'figure');
                uialert(fig, ME.message, 'Eroare la Visualise');
            end
        end

        % 2) REDESENARE pentru pasul k
        function renderGaussStep(app, k)
            pivots   = app.GaussData.pivots;
            sizes    = app.GaussData.sizes;
            updates  = app.GaussData.updates;
            AcurList = app.GaussData.AcurList;

            % --- Heatmap cu valori ---
            cla(app.MatrixAxes);
            M = AcurList{k};
            imagesc(app.MatrixAxes, M);
            colormap(app.MatrixAxes,'hot');
            axis(app.MatrixAxes,'equal','tight');
            title(app.MatrixAxes, sprintf('Pas %d', k), 'FontWeight','bold');
            colorbar(app.MatrixAxes);
            rectangle(app.MatrixAxes, 'Position',[k-0.5,k-0.5,1,1], ...
                      'EdgeColor','cyan','LineWidth',2);

            % suprapunem valorile celulelor
            [nr, nc] = size(M);
            for i = 1:nr
                for j = 1:nc
                    text(app.MatrixAxes, j, i, sprintf('%.2f', M(i,j)), ...
                         'HorizontalAlignment','center', ...
                         'VerticalAlignment','middle', ...
                         'Color','w','FontWeight','bold','FontSize',10);
                end
            end

            % --- Grafic progres ---
            cla(app.ProgressAxesGauss);
            steps = 1:numel(pivots);
            yyaxis(app.ProgressAxesGauss,'left');
                plot(app.ProgressAxesGauss, steps(1:k), pivots(1:k), '-o','LineWidth',1.5);
                ylabel(app.ProgressAxesGauss,'Pivot a_{kk}');
            yyaxis(app.ProgressAxesGauss,'right');
                plot(app.ProgressAxesGauss, steps(1:k), sizes(1:k), '-s','LineWidth',1.5);
                ylabel(app.ProgressAxesGauss,'Dimensiune sub-sistem');
            xlabel(app.ProgressAxesGauss,'Pas');
            title(app.ProgressAxesGauss,'Evoluția pivotului și a dimensiunii','FontWeight','bold');
            legend(app.ProgressAxesGauss,{'Pivot','Dimensiune'},'Location','best');
            grid(app.ProgressAxesGauss,'on');

            % --- Tabel cu primele k rânduri ---
            app.StepsTableGauss.ColumnName = {'Pas','Pivot','Dimensiune','Element','Formulă','Valoare'};
            % selectăm rândurile ale căror coloana Pas ≤ k
            sel = cell2mat(updates(:,1)) <= k;
            app.StepsTableGauss.Data = updates(sel, :);
        end

        % 3) CALLBACK slider: redă pasul ales
        function SpeedSliderValueChanged(app, event)
            k = round(app.SpeedSlider.Value);
            renderGaussStep(app, k);


        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
              msg = sprintf([ ...
        '🔸 Metoda Gauss simplu rezolvă sistemul A·x = b prin:\n' ...
        '- Eliminare directă a necunoscutelor rând pe rând.\n' ...
        '- Nu folosește pivotare (atenție la zero pe diagonală!)\n\n' ...
        '🔹 Formule:\n' ...
        'Pentru i > k:\n' ...
        '    A(i,j) = A(i,j) - A(i,k)/A(k,k) * A(k,j)\n' ...
        '    b(i)   = b(i)   - A(i,k)/A(k,k) * b(k)\n\n' ...
        '🔹 Complexitate: O(n³)\n' ...
        '🔹 Sensibil la erori numerice.\n']);
 parentFig = ancestor(app.SolveButton, 'figure');  
uialert(parentFig, msg, 'Metoda Gauss simplu');
        end

        % Callback function: not associated with a component
        function ReturnButtonPushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.Gauss);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GausApp = uifigure('Visible', 'off');
            app.GausApp.Name = 'MATLAB App';
            app.ProgressAxesGauss = uiaxes(app.GausApp);
            title(app.ProgressAxesGauss, 'Progres')
            xlabel(app.ProgressAxesGauss, 'X')
            ylabel(app.ProgressAxesGauss, 'Y')
            zlabel(app.ProgressAxesGauss, 'Z')
            app.ProgressAxesGauss.Tag = 'ProgressAxesGauss';
            app.MatrixAxes = uiaxes(app.GausApp);
            title(app.MatrixAxes, 'MatrixAxes')
            xlabel(app.MatrixAxes, 'X')
            ylabel(app.MatrixAxes, 'Y')
            zlabel(app.MatrixAxes, 'Z')
            app.UITableX = uitable(app.GausApp);
            app.UITableX.ColumnName = {'Result'};
            app.UITableX.RowName = {};
            app.SolveButton = uibutton(app.GausApp, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITableB = uitable(app.GausApp);
            app.UITableB.ColumnName = {'b'};
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.UITable = uitable(app.GausApp);
            app.UITable.ColumnName = {'Coloana 1'; 'Coloana 2'; 'Coloana 3'; 'Coloana 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DimensiuneLabel = uilabel(app.GausApp);
            app.DimensiuneLabel.Text = 'Dimensiune';
            app.NField = uieditfield(app.GausApp, 'numeric');
            app.NField.Limits = [1 Inf];
            app.NField.Value = 2;
            app.SetSizeButton = uibutton(app.GausApp, 'push');
            app.SetSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetSizeButtonPushed, true);
            app.SetSizeButton.Text = 'SetSize';
            app.Gauss = uilabel(app.GausApp);
            app.Gauss.Text = 'Gauss';
            app.StepsTableGauss = uitable(app.GausApp);
            app.StepsTableGauss.ColumnName = {'pas'; 'pivot'; 'dimensiune'};
            app.StepsTableGauss.RowName = {};
            app.StepsTableGauss.Tag = 'StepsTableGauss';
            app.VizualizeazapasiiButton = uibutton(app.GausApp, 'push');
            app.VizualizeazapasiiButton.ButtonPushedFcn = createCallbackFcn(app, @VizualizeazapasiiButtonPushed, true);
            app.VizualizeazapasiiButton.Tag = 'VizGaussButton';
            app.VizualizeazapasiiButton.Text = 'Vizualizeaza pasii';
            app.InfoButton = uibutton(app.GausApp, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tag = 'InfoButton';
            app.InfoButton.Text = 'Info';
            app.PasiiProblemei = uilabel(app.GausApp);
            app.PasiiProblemei.Text = 'Pasii Problemei';
            app.LabelA = uilabel(app.GausApp);
            app.LabelA.Text = 'A';
            app.VitezaanimatieLabel = uilabel(app.GausApp);
            app.VitezaanimatieLabel.Text = 'Viteza animatie';
            app.SpeedSlider = uislider(app.GausApp);
            app.SpeedSlider.Tag = 'SpeedSlider';
            app.SpeedSlider.Value = 10;
            app.ReturnButton_2 = uibutton(app.GausApp, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.GausApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GausApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GausApp)
        end
    end
end