classdef gaussPivotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ReturnButton_2              matlab.ui.control.Button
        SpeedSlider                 matlab.ui.control.Slider
        SliderLabel                 matlab.ui.control.Label
        ALabel                      matlab.ui.control.Label
        bLabel                      matlab.ui.control.Label
        PasiLabel                   matlab.ui.control.Label
        InfoButton                  matlab.ui.control.Button
        VizualizeazaButton          matlab.ui.control.Button
        StepsTablePivot             matlab.ui.control.Table
        GaussPivotarePartialaLabel  matlab.ui.control.Label
        UITableX                    matlab.ui.control.Table
        SolveButton                 matlab.ui.control.Button
        UITableB                    matlab.ui.control.Table
        UITableA                    matlab.ui.control.Table
        SetSizeButton               matlab.ui.control.Button
        nEditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        MatrixAxes                  matlab.ui.control.UIAxes
        ProgressAxesPivot           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PivotData   
        Property % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetSizeButton
        function SetSizeButtonPushed(app, event)
               % Preia dimensiunea n
            n = round(app.nEditField.Value);
            % Redimensionează tabelele
            app.UITableA.ColumnName = repmat({''},1,n);
            app.UITableA.Data       = num2cell(zeros(n,n));
            app.UITableB.ColumnName = {'b'};
            app.UITableB.Data       = num2cell(zeros(n,1));
            % Golește tabelul de soluții
            app.UITableX.Data = {};
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

  % 1) Citește date din tabele
            rawA = app.UITableA.Data;
            rawb = app.UITableB.Data;
            if iscell(rawA), A = cell2mat(rawA); 
            else
                A = rawA;
            end
            if iscell(rawb), b = cell2mat(rawb);
            else
                b = rawb;
            end
            b = b(:);

            % 2) Apelează funcția pivot și afișează
            try
                x = gaussSolvePivot(A, b);
                app.UITableX.Data = num2cell(x);
            catch ME
                uialert(app.UIFigure, ME.message, 'Eroare la Gauss Pivot');
            end
        end

        % Button pushed function: VizualizeazaButton
        function VizualizeazaButtonPushed(app, event)
      
           try
            % 1) Citește A și b
            rawA = app.UITableA.Data;
            rawb = app.UITableB.Data;
            if iscell(rawA), A = cell2mat(rawA); else A = rawA; end
            if iscell(rawb), b = cell2mat(rawb); else b = rawb; end
            b = b(:);
            n = size(A,1);
            if n~=size(A,2) || numel(b)~=n
                error('A trebuie pătratică şi b de dimensiune n×1.');
            end

            % 2) Calculează pivots și sizes
            [pivots, sizes] = gaussPivotProgressData(A, b);

            % 3) Construiește AcurList, updates și rowIdx
            Acur    = [A, b];
            updates = {};
            rowIdx  = zeros(n-1,1);
            for k = 1:n-1
                AcurList{k} = Acur;

                % selectare pivot
                [~, idx]    = max(abs(Acur(k:n,k)));
                iMax        = idx + k - 1;
                pivotVal    = Acur(iMax,k);
                dimVal      = n - k + 1;
                rowIdx(k)   = iMax;

                updates(end+1,:) = {k, pivotVal, dimVal, ...
                    sprintf('a_{%d%d}',iMax,k), 'pivot selectat', pivotVal};

                % swap rânduri
                if iMax~=k
                    Acur([k iMax],:) = Acur([iMax k],:);
                    updates(end+1,:) = {k, pivotVal, dimVal, ...
                        sprintf('swap %d↔%d',k,iMax), 'swap rânduri', NaN};
                end

                % eliminare Gauss
                a11 = Acur(k,k);
                for i = k+1:n
                    factor = Acur(i,k)/a11;
                    for j = k+1:n+1
                        old    = Acur(i,j);
                        akj    = Acur(k,j);
                        newVal = (a11*old - Acur(i,k)*akj)/a11;
                        Acur(i,j) = newVal;
                        updates(end+1,:) = {k, pivotVal, dimVal, ...
                            sprintf('a_{%d%d}',i,j), ...
                            sprintf('(%.4g·%.4g - %.4g·%.4g)/%.4g', ...
                                    a11, old, Acur(i,k), akj, a11), newVal};
                    end
                    Acur(i,k) = 0;
                end
            end

            % 4) Salvează în app.PivotData
            app.PivotData.pivots   = pivots;
            app.PivotData.sizes    = sizes;
            app.PivotData.rows     = rowIdx;
            app.PivotData.updates  = updates;
            app.PivotData.AcurList = AcurList;
            app.PivotData.n        = n;

            % 5) Configurează slider‑ul
            app.SpeedSlider.Limits       = [1, n-1];
            app.SpeedSlider.MajorTicks   = 1:(n-1);
            app.SpeedSlider.Value        = 1;
            app.SpeedSlider.ValueChangedFcn = @(s,e) pivotSliderChanged(app,e);

            % 6) Desenează pasul 1
            renderPivotStep(app, 1);

        catch ME
            uialert(app.UIFigure, ME.message, 'Eroare Vizualizare');
        

           end


    % --- Desenează pasul k, heatmap + progres + tabel ---
    function renderPivotStep(app, k)
        pivots   = app.PivotData.pivots;
        sizes    = app.PivotData.sizes;
        rows     = app.PivotData.rows;
        updates  = app.PivotData.updates;
        AcurList = app.PivotData.AcurList;

        % 1) Heatmap cu starea curentă
        cla(app.MatrixAxes);
        M = AcurList{k};
        imagesc(app.MatrixAxes, M);
        colormap(app.MatrixAxes,'parula');
        axis(app.MatrixAxes,'equal','tight');
        colorbar(app.MatrixAxes);

        % evidențierea pivotului exact: (rowIdx(k), k)
        r = rows(k);
        c = k;
        rectangle(app.MatrixAxes, ...
            'Position',[c-0.5, r-0.5, 1, 1], ...
            'EdgeColor','red','LineWidth',2);

        % titlu care afișează valoarea pivot și rândul
       title(app.MatrixAxes, ...
    sprintf('Pivotul la pasul  %d: rând %d', k, r), ...
    'FontWeight','bold','Color','blue');

        % suprapunere valori pe heatmap
        [nr,nc] = size(M);
        for i = 1:nr
            for j = 1:nc
                text(app.MatrixAxes, j, i, sprintf('%.2f', M(i,j)), ...
                     'HorizontalAlignment','center', ...
                     'VerticalAlignment','middle', ...
                     'Color','white','FontWeight','bold');
            end
        end

        % 2) Grafic progres pivot vs dimensiune
        cla(app.ProgressAxesPivot);
        steps = 1:numel(pivots);
        yyaxis(app.ProgressAxesPivot,'left');
            plot(app.ProgressAxesPivot, steps(1:k), pivots(1:k), '-o', 'LineWidth',1.5);
            hold(app.ProgressAxesPivot,'on');
            plot(app.ProgressAxesPivot, k, pivots(k), 'ro', 'MarkerSize',8, 'LineWidth',2);
            ylabel(app.ProgressAxesPivot,'Pivot a_{kk}');
        yyaxis(app.ProgressAxesPivot,'right');
            plot(app.ProgressAxesPivot, steps(1:k), sizes(1:k), '-s', 'LineWidth',1.5);
            ylabel(app.ProgressAxesPivot,'Dimensiune sub‑sistem');
        hold(app.ProgressAxesPivot,'off');
        xlabel(app.ProgressAxesPivot,'Pas');
        title(app.ProgressAxesPivot,'Progres Gauss Pivotare','FontWeight','bold');
        legend(app.ProgressAxesPivot,{'Pivot','Curent','Dimensiune'},'Location','best');
        grid(app.ProgressAxesPivot,'on');

        % 3) Populează tabelul cu pași
        sel = cell2mat(updates(:,1)) <= k;
        app.StepsTablePivot.ColumnName = {'Pas','Pivot','Dimensiune','Element','Formulă','Valoare'};
        app.StepsTablePivot.Data       = updates(sel,:);
    end

    % --- Callback pentru slider ---
    function pivotSliderChanged(app, event)
        k = round(app.SpeedSlider.Value);
        renderPivotStep(app, k);
    end
   
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
              msg = sprintf([ ...
        '🔸 Gauss cu pivotare parțială este o îmbunătățire a metodei clasice,\n' ...
        'prin alegerea celui mai mare pivot (în modul) pe fiecare coloană:\n\n' ...
        '- Se evită divizări prin 0 sau numere foarte mici.\n' ...
        '- Se schimbă rândurile dacă este necesar.\n\n' ...
        '🔹 Formule:\n' ...
        'Pentru i > k:\n' ...
        '    factor = A(i,k) / A(k,k)\n' ...
        '    A(i,j) = A(i,j) - factor * A(k,j)\n' ...
        '    b(i)   = b(i)   - factor * b(k)\n\n' ...
        '🔹 Recomandată pentru calcule numerice precise.\n']);
    uialert(app.UIFigure, msg, 'Gauss cu Pivotare');

        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgressAxesPivot = uiaxes(app.UIFigure);
            title(app.ProgressAxesPivot, 'Progress axes')
            xlabel(app.ProgressAxesPivot, 'X')
            ylabel(app.ProgressAxesPivot, 'Y')
            zlabel(app.ProgressAxesPivot, 'Z')
            app.ProgressAxesPivot.Tag = 'ProgressAxesPivot';
            app.MatrixAxes = uiaxes(app.UIFigure);
            title(app.MatrixAxes, 'MatrixAxes')
            xlabel(app.MatrixAxes, 'X')
            ylabel(app.MatrixAxes, 'Y')
            zlabel(app.MatrixAxes, 'Z')
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Tag = 'NField';
            app.nEditFieldLabel.Text = 'n=';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetSizeButton = uibutton(app.UIFigure, 'push');
            app.SetSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetSizeButtonPushed, true);
            app.SetSizeButton.Tag = 'SetSizeButton';
            app.SetSizeButton.Text = 'SetSize';
            app.UITableA = uitable(app.UIFigure);
            app.UITableA.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.UITableB = uitable(app.UIFigure);
            app.UITableB.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Tag = 'SolveButton';
            app.SolveButton.Text = 'Solve';
            app.UITableX = uitable(app.UIFigure);
            app.UITableX.ColumnName = {'Column 1'};
            app.UITableX.RowName = {};
            app.GaussPivotarePartialaLabel = uilabel(app.UIFigure);
            app.GaussPivotarePartialaLabel.Text = 'Gauss Pivotare Partiala';
            app.StepsTablePivot = uitable(app.UIFigure);
            app.StepsTablePivot.ColumnName = {'Pas'; 'Pivot'; 'Dimensiune'};
            app.StepsTablePivot.RowName = {};
            app.VizualizeazaButton = uibutton(app.UIFigure, 'push');
            app.VizualizeazaButton.ButtonPushedFcn = createCallbackFcn(app, @VizualizeazaButtonPushed, true);
            app.VizualizeazaButton.Tag = 'VizPivotButton';
            app.VizualizeazaButton.Text = 'Vizualizeaza';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tag = 'InfoButton';
            app.InfoButton.Text = 'Info';
            app.PasiLabel = uilabel(app.UIFigure);
            app.PasiLabel.Text = 'Pasi';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.SpeedSlider = uislider(app.UIFigure);
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gaussPivotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end