classdef kyrlovApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ReturnButton_2            matlab.ui.control.Button
        UITableY0                 matlab.ui.control.Table
        SetMatrixSizeButton       matlab.ui.control.Button
        ProgressTable             matlab.ui.control.Table
        CoeffsTable               matlab.ui.control.Table
        InfoButton                matlab.ui.control.Button
        VisualiseButton           matlab.ui.control.Button
        SolveButton               matlab.ui.control.Button
        MatrixSizeField           matlab.ui.control.NumericEditField
        DimensiuneamatriceiLabel  matlab.ui.control.Label
        UITableA                  matlab.ui.control.Table
        KrylovsmethodfortodeterminethecoefficientsofcharacteristicpolynomialLabel  matlab.ui.control.Label
        ProgressAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
        try
        % 1) Preia matricea A
        rawA = app.UITableA.Data;
        if iscell(rawA)
            A = cell2mat(rawA);
        else
            A = rawA;
        end

        % 2) Preia vectorul y0
        rawY0 = app.UITableY0.Data;
        if iscell(rawY0)
            y0 = cellfun(@double, rawY0);
        elseif isnumeric(rawY0)
            y0 = rawY0;
        else
            error('Format invalid pentru y0.');
        end
        y0 = y0(:);

        % 3) Validări de bază
        [nA, mA] = size(A);
        if nA~=mA
            uialert(app.UIFigure,'A trebuie pătratică','Eroare');
            return;
        end
        if numel(y0)~=nA
            uialert(app.UIFigure,'y0 trebuie de aceeași dimensiune','Eroare');
            return;
        end

        % 4) Apelează helper-ul corect
        data = krylovProgressData(A, y0);

        % 5) Extrage coeficienții c1…cn
        c = data.C;    % 1×n

        % 6) Populează CoeffsTable
        names = arrayfun(@(k) sprintf('c%d',k), 1:numel(c), 'uni',0);
        app.CoeffsTable.ColumnName = names;
        app.CoeffsTable.Data       = num2cell(c);

    catch ME
        uialert(app.UIFigure, ME.message, 'Eroare Solve');
        end

         
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
          
          try
        % 1) Citește A și y0
        rawA  = app.UITableA.Data;
        rawY0 = app.UITableY0.Data;
        if iscell(rawA),  A = cell2mat(rawA);  else A = rawA;  end
        if iscell(rawY0), y0 = cell2mat(rawY0); else y0 = rawY0; end
        y0 = y0(:);

        % 2) Obține datele Krylov
        data = krylovProgressData(A, y0);
        n    = size(A,1);

        % 3) Construiește prima table T
        steps = (0:n)';
        T = table(steps, data.Formula, 'VariableNames', {'Step','Formula'});
        for i = 1:n
            T.(sprintf('y%d',i)) = data.Y(i,:)';
        end
        condCol = repmat({''},n+1,1); condCol{n+1}=data.CondB;
        T.CondB = condCol;
        norms = vecnorm(data.Y);
        T.NormY = cellstr(rat(norms',1e-6));

        % Extragem celulele și numele coloanelor
        colNames = T.Properties.VariableNames;
        baseData = table2cell(T);       % (n+1)×numCols
        numCols  = size(baseData,2);

        % 4) Construim cell-ul cu rânduri extra
        extraCells = {};  % vom adăuga rând cu rând

        % 4a) coeficienți c1...cn
        for k = 1:n
            row = repmat({''}, 1, numCols);
            row{2} = sprintf('c%d',k);
            row{numCols-4} = rat(data.C(k),1e-6);  % punem în coloana Coeffs
            extraCells(end+1,:) = row;
        end

        % 4b) eigenvalues
        for k = 1:n
            row = repmat({''}, 1, numCols);
            row{2} = sprintf('eig%d',k);
            row{numCols-3} = rat(data.EigVals(k),1e-6);
            extraCells(end+1,:) = row;
        end

        % 4c) eigenvectors
        for k = 1:n
            row = repmat({''}, 1, numCols);
            row{2} = sprintf('v%d',k);
            % vectorul liniu de eigenvectori
            row{numCols-2} = mat2str(rat(data.EigVecs(:,k),1e-6));
            extraCells(end+1,:) = row;
        end

        % 4d) inversa lui A, rând cu rând
        for k = 1:n
            row = repmat({''}, 1, numCols);
            row{2} = sprintf('invA_row%d',k);
            row{numCols-1} = mat2str(rat(data.InvA(k,:),1e-6));
            extraCells(end+1,:) = row;
        end

        % 5) concatenăm datele
        allData = [baseData; extraCells];

        % 6) Populează uitable
        app.ProgressTable.ColumnName = colNames;
        app.ProgressTable.Data       = allData;

        % 7) Dual‑axis plot
        ax = app.ProgressAxes; cla(ax);
        steps = 0:n;
        condPlot = nan(1,n+1); condPlot(end)=data.CondB;
        normPlot = norms;
        yyaxis(ax,'left')
            plot( ax, steps, condPlot, '-o','LineWidth',1.5);
            ylabel(ax,'cond(B)');
        yyaxis(ax,'right')
            plot( ax, steps, normPlot, '-s','LineWidth',1.5);
            ylabel(ax,'||y||');
        xlabel(ax,'k');
        title(ax,'Krylov Progress');
        legend(ax,{'cond(B)','||y||'},'Location','best');
        grid(ax,'on');

    catch ME
        uialert(app.UIFigure, ME.message, 'Error Visualise');
          end


        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
             msg = [ ...
          "Metoda Krylov determină coeficienții polinomului caracteristic p_A(λ).", newline,...
          "1) Construiește y^(k)=A·y^(k-1) pentru k=1..n", newline,...
          "2) Formați B=[y^(n) … y^(1)] și rhs=−y^(n+1)", newline,...
          "3) Rezolvați B·c = rhs → c = [c1…cn]", newline,...
          "4) p_A(λ)= λ^n + c1 λ^(n-1)+…+cn", newline,...
          "Dacă B nu e inversabilă, alegeți alt y^(0) și reapelați." ];
        uialert(app.UIFigure, strjoin(msg, newline), 'Despre Krylov');
        end

        % Button pushed function: SetMatrixSizeButton
        function SetMatrixSizeButtonPushed(app, event)
             n = app.MatrixSizeField.Value;

    % Update matrix A table
    app.UITableA.Data = zeros(n, n);
    app.UITableA.ColumnName = strcat("c", string(1:n));
    app.UITableA.RowName = strcat("r", string(1:n));

    % Update y0 table
    app.UITableY0.Data = zeros(n, 1);
    app.UITableY0.ColumnName = "y₀";
    app.UITableY0.RowName = strcat("r", string(1:n));
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgressAxes = uiaxes(app.UIFigure);
            title(app.ProgressAxes, 'Progress Axes')
            xlabel(app.ProgressAxes, 'X')
            ylabel(app.ProgressAxes, 'Y')
            zlabel(app.ProgressAxes, 'Z')
            app.KrylovsmethodfortodeterminethecoefficientsofcharacteristicpolynomialLabel = uilabel(app.UIFigure);
            app.KrylovsmethodfortodeterminethecoefficientsofcharacteristicpolynomialLabel.Text = 'Krylov’s method for to determine the coefficients of characteristic polynomial';
            app.UITableA = uitable(app.UIFigure);
            app.UITableA.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.DimensiuneamatriceiLabel = uilabel(app.UIFigure);
            app.DimensiuneamatriceiLabel.Text = 'Dimensiunea matricei';
            app.MatrixSizeField = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.CoeffsTable = uitable(app.UIFigure);
            app.CoeffsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CoeffsTable.RowName = {};
            app.ProgressTable = uitable(app.UIFigure);
            app.ProgressTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProgressTable.RowName = {};
            app.ProgressTable.ColumnEditable = true;
            app.SetMatrixSizeButton = uibutton(app.UIFigure, 'push');
            app.SetMatrixSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetMatrixSizeButtonPushed, true);
            app.SetMatrixSizeButton.Text = 'SetMatrixSize';
            app.UITableY0 = uitable(app.UIFigure);
            app.UITableY0.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableY0.RowName = {};
            app.UITableY0.ColumnEditable = true;
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kyrlovApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end