classdef newtonTangentApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReturnButton_2               matlab.ui.control.Button
        PasiidetaliatiLabel          matlab.ui.control.Label
        DerivLabel                   matlab.ui.control.Label
        FunctionField                matlab.ui.control.EditField
        FunctionFieldEditFieldLabel  matlab.ui.control.Label
        NewtonStepsTable             matlab.ui.control.Table
        ResultLabel                  matlab.ui.control.Label
        VisualiseButton              matlab.ui.control.Button
        InfoButton                   matlab.ui.control.Button
        SolveButton                  matlab.ui.control.Button
        IterField                    matlab.ui.control.NumericEditField
        IterFieldLabel               matlab.ui.control.Label
        EpsField                     matlab.ui.control.NumericEditField
        EpsFieldLabel                matlab.ui.control.Label
        X0Field                      matlab.ui.control.NumericEditField
        x0Label                      matlab.ui.control.Label
        NewtonTangentForAproximationLabel  matlab.ui.control.Label
        NewtonAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NewtonXVals   % va stoca vectorul x_vals
    NewtonDiffs   
        Property % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
             msg = [
        "Metoda Newton (tangentei) rezolvă ecuația f(x) = 0 iterativ:"
        " "
        "xₙ₊₁ = xₙ - f(xₙ) / f'(xₙ)"
        " "
        "Condiții de convergență:"
        "- f(a)*f(b) < 0"
        "- f'(x) ≠ 0 și f''(x) ≠ 0 pe [a, b]"
        "- x₀ se alege astfel încât f(x₀)*f''(x₀) > 0"
        " "
        "Iterațiile se opresc când |xₙ₊₁ - xₙ| ≤ ε"
    ];
    uialert(app.UIFigure, strjoin(msg, newline), 'Despre metoda Newton');
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            try
        % 1) Build function handle from text
                expr = vectorize(app.FunctionField.Value);
                f = str2func(['@(x)' expr]);

                % 2) Read parameters
                x0    = app.X0Field.Value;
                eps   = app.EpsField.Value;
                itmax = app.IterField.Value;

                % 3) Run Newton with numeric derivative
                [x_vals, diffs, ~] = newtonProgressDataNumDeriv(f, x0, eps, itmax);

                % 4) Populate steps table
                data = [ num2cell(x_vals(1:end-1).'), num2cell(diffs.') ];
                app.NewtonStepsTable.ColumnName = {'x_n','|x_{n+1}-x_n|'};
                app.NewtonStepsTable.Data       = data;

                % 5) Show result
                N = numel(diffs);
                app.ResultLabel.Text = sprintf('x ≈ %.6f after %d iterations', x_vals(end), N);

                % 6) Store for visualisation
                app.NewtonXVals = x_vals;
                app.NewtonDiffs = diffs;

            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end


        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
             try
            % 1) Construiește f(x)
            expr  = vectorize(app.FunctionField.Value);
            f     = str2func(['@(x)' expr]);
            x0    = app.X0Field.Value;
            eps   = app.EpsField.Value;
            itmax = app.IterField.Value;
            h     = 1e-6;

            % Înainte de iteratii: afișează formula derivatei
            app.DerivLabel.Text = "f'(x) ≈ (f(x+h)-f(x-h))/(2·h)";

            % 2) Iterează și strânge datele
            steps = [];
            for k = 1:itmax
                fx   = f(x0);
                dfx  = (f(x0+h) - f(x0-h)) / (2*h);
                if k==1
                    % Afișează valoarea derivatei la x0
                    app.DerivLabel.Text = sprintf("f'(%.6f) ≈ %.6f", x0, dfx);
                end
                if dfx == 0, break; end
                x1   = x0 - fx/dfx;
                dif  = abs(x1 - x0);
                steps(end+1, :) = [k, x0, fx, dfx, x1, dif]; %#ok<AGROW>
                if dif <= eps, break; end
                x0 = x1;
            end

            % 3) Populează tabelul
            app.NewtonStepsTable.ColumnName = { ...
              'Iter','x_n','f(x_n)','f''''(x_n)','x_{n+1}','|Δ|'};
            app.NewtonStepsTable.Data = num2cell(steps);

            % 4) Plotează eroarea
            iters = steps(:,1);
            errs  = steps(:,6);
            cla(app.NewtonAxes);
            plot(app.NewtonAxes, iters, errs, '-o','LineWidth',1.5);
            xlabel(app.NewtonAxes,'Iterație');
            ylabel(app.NewtonAxes,'|x_{n+1}-x_n|');
            title(app.NewtonAxes,'Evoluția erorii');
            grid(app.NewtonAxes,'on');

        catch ME
            uialert(app.UIFigure, ME.message, 'Eroare Visualise');
             end


        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewtonAxes = uiaxes(app.UIFigure);
            title(app.NewtonAxes, 'Newton Axes')
            xlabel(app.NewtonAxes, 'X')
            ylabel(app.NewtonAxes, 'Y')
            zlabel(app.NewtonAxes, 'Z')
            app.NewtonTangentForAproximationLabel = uilabel(app.UIFigure);
            app.NewtonTangentForAproximationLabel.Text = 'Newton Tangent For Aproximation';
            app.x0Label = uilabel(app.UIFigure);
            app.x0Label.Text = 'x0';
            app.X0Field = uieditfield(app.UIFigure, 'numeric');
            app.X0Field.Tag = 'X0Field';
            app.EpsFieldLabel = uilabel(app.UIFigure);
            app.EpsFieldLabel.Text = 'EpsField';
            app.EpsField = uieditfield(app.UIFigure, 'numeric');
            app.IterFieldLabel = uilabel(app.UIFigure);
            app.IterFieldLabel.Text = 'IterField';
            app.IterField = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.NewtonStepsTable = uitable(app.UIFigure);
            app.NewtonStepsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.NewtonStepsTable.RowName = {};
            app.FunctionFieldEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionFieldEditFieldLabel.Text = 'Function Field';
            app.FunctionField = uieditfield(app.UIFigure, 'text');
            app.FunctionField.Tag = 'FunctionField';
            app.DerivLabel = uilabel(app.UIFigure);
            app.DerivLabel.Text = '';
            app.PasiidetaliatiLabel = uilabel(app.UIFigure);
            app.PasiidetaliatiLabel.Text = 'Pasii detaliati';
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtonTangentApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end