classdef polygonIntegralApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReturnButton_2                 matlab.ui.control.Button
        PasiidetaliatiLabel            matlab.ui.control.Label
        FunctionField                  matlab.ui.control.EditField
        FunctionLabel                  matlab.ui.control.Label
        ProgressTable                  matlab.ui.control.Table
        ResultLabel                    matlab.ui.control.Label
        Y0Field                        matlab.ui.control.NumericEditField
        Y0Label                        matlab.ui.control.Label
        X0Field                        matlab.ui.control.NumericEditField
        x0Label                        matlab.ui.control.Label
        VertexTable                    matlab.ui.control.Table
        NField                         matlab.ui.control.NumericEditField
        nfieldLabel                    matlab.ui.control.Label
        VisualiseButton                matlab.ui.control.Button
        SolveButton                    matlab.ui.control.Button
        SetupButton                    matlab.ui.control.Button
        InfoButton                     matlab.ui.control.Button
        PolygonIntegralCalculateLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
           msg = [ ...
      "Metoda aproximării dublelor integrale pe domeniu poligonal:", newline,...
      "1) Se alege un punct interior A0(x0,y0).", newline,...
      "2) D se împarte în triunghiurile (Ai, A0, Ai+1).", newline,...
      "3) Fiecare triunghi are:", newline,...
      "   l1 = dist(Ai,A0), l2 = dist(Ai+1,A0), l3 = dist(Ai,Ai+1)", newline,...
      "   p = (l1+l2+l3)/2,  S = sqrt(p(p−l1)(p−l2)(p−l3))", newline,...
      "   centroid = ((xi+x0+xi+1)/3, (yi+y0+yi+1)/3)", newline,...
      "   Ji = S/12 [ f(Ai)+f(A0)+f(Ai+1)+9·f(centroid) ]", newline,...
      "4) Se însumează toate Ji → I ≈ ∬ f(x,y)dA.", newline,...
      "Introduceţi f(x,y) în câmpul de sus, apoi Setup, completaţi tabela şi", ...
      "folosiţi Solve (valoare finală) sau Visualise (paşi + grafic)." ];
    uialert(app.UIFigure, msg, 'Despre Metodă');

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
             try
        % 1) Citește f(x,y) symbolic
        expr = app.FunctionField.Value;
        fhandle = str2func(['@(x,y) ' vectorize(expr)]);
        
        % 2) Citește noduri și punct interior
        raw    = app.VertexTable.Data;
        xv     = cell2mat(raw(:,1))';
        yv     = cell2mat(raw(:,2))';
        x0     = app.X0Field.Value;
        y0     = app.Y0Field.Value;

        % 3) Calculează integrală
        I = polyIntegral(xv,yv,x0,y0,fhandle);

        % 4) Afișează în ResultLabel
        app.ResultLabel.Text = sprintf('I ≈ %.6g', I);
    catch ME
        uialert(app.UIFigure, ['Eroare Solve: ' ME.message], 'Eroare');
             end
             
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
             try
        % 1) Citește f, noduri și punct
        expr    = app.FunctionField.Value;
        fhandle = str2func(['@(x,y) ' vectorize(expr)]);
        raw     = app.VertexTable.Data;
        xv      = cell2mat(raw(:,1))';
        yv      = cell2mat(raw(:,2))';
        x0      = app.X0Field.Value;
        y0      = app.Y0Field.Value;

        % 2) Progres detaliat
        T = polyIntegralProgress(xv,yv,x0,y0,fhandle);
        app.ProgressTable.ColumnName = T.Properties.VariableNames;
        app.ProgressTable.Data       = table2cell(T);

        % 3) Plot domain şi triunghiuri
        cla(app.UIAxes); hold(app.UIAxes,'on'); grid(app.UIAxes,'on');
        patch(app.UIAxes, xv, yv, .9*[1 1 1], 'EdgeColor','k','FaceAlpha',.2);
        for i = 1:numel(xv)
            if i< numel(xv), j=i+1; else j=1; end
            X=[xv(i) x0 xv(j)]; Y=[yv(i) y0 yv(j)];
            patch(app.UIAxes, X,Y,rand(1,3),'FaceAlpha',.3);
            % centroid
            xc=(X(1)+X(2)+X(3))/3; yc=(Y(1)+Y(2)+Y(3))/3;
            plot(app.UIAxes, xc,yc,'kp','MarkerFaceColor','y');
        end
        plot(app.UIAxes, xv,yv,'ro','MarkerFaceColor','r');
        plot(app.UIAxes, x0,y0,'b*','MarkerSize',10);

        axis(app.UIAxes,'equal');
        title(app.UIAxes,'Domeniu + triunghiuri');
        hold(app.UIAxes,'off');
    catch ME
        uialert(app.UIFigure, ['Eroare Vizualizare: ' ME.message], 'Eroare');
             end
             
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
             % 1) Citește n
    n = round(app.NField.Value);
    if n < 3
        uialert(app.UIFigure, 'Trebuie n ≥ 3 pentru un poligon.', 'Eroare');
        return;
    end

    % 2) Redimensionează tabelul de vârfuri la n rânduri, 2 coloane [x y]
    app.VertexTable.Data       = num2cell(nan(n,2));
    app.VertexTable.ColumnName = {'x','y'};
    app.VertexTable.RowName    = strcat('A',string(1:n))';

    % 3) Şterge rezultatele vechi
    app.ResultLabel.Text    = '';
    app.ProgressTable.Data  = {};
    if isprop(app,'DDTable')
        app.DDTable.Data    = {};
    end
    cla(app.UIAxes);

    % 4) Focalizează cursorul în prima celulă
    drawnow;  % asigură actualizarea UI
    uialert(app.UIFigure, sprintf('Gata pentru n=%d. Introdu coordonatele!',n),'Setup OK');
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Axa Progres')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PolygonIntegralCalculateLabel = uilabel(app.UIFigure);
            app.PolygonIntegralCalculateLabel.Text = 'Polygon Integral Calculate';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.nfieldLabel = uilabel(app.UIFigure);
            app.nfieldLabel.Text = 'nfield';
            app.NField = uieditfield(app.UIFigure, 'numeric');
            app.VertexTable = uitable(app.UIFigure);
            app.VertexTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.VertexTable.RowName = {};
            app.VertexTable.ColumnEditable = true;
            app.x0Label = uilabel(app.UIFigure);
            app.x0Label.Text = 'x0';
            app.X0Field = uieditfield(app.UIFigure, 'numeric');
            app.Y0Label = uilabel(app.UIFigure);
            app.Y0Label.Text = 'Y0';
            app.Y0Field = uieditfield(app.UIFigure, 'numeric');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            app.ProgressTable = uitable(app.UIFigure);
            app.ProgressTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProgressTable.RowName = {};
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function';
            app.FunctionField = uieditfield(app.UIFigure, 'text');
            app.PasiidetaliatiLabel = uilabel(app.UIFigure);
            app.PasiidetaliatiLabel.Text = 'Pasii detaliati';
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polygonIntegralApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end