classdef seidelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReturnButton_2                 matlab.ui.control.Button
        PasidetaliatiLabel             matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        MatriceatermenilorliberiLabel  matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        UITableX                       matlab.ui.control.Table
        X0Field                        matlab.ui.control.EditField
        X0EditFieldLabel               matlab.ui.control.Label
        ShowStepsButton                matlab.ui.control.Button
        StepsTable                     matlab.ui.control.Table
        SolveButton                    matlab.ui.control.Button
        SetSizeButton                  matlab.ui.control.Button
        IterField                      matlab.ui.control.NumericEditField
        IterFieldLabel                 matlab.ui.control.Label
        EpsField                       matlab.ui.control.NumericEditField
        EpsFieldLabel                  matlab.ui.control.Label
        NField                         matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        InfoButton                     matlab.ui.control.Button
        UITableB                       matlab.ui.control.Table
        UITableA                       matlab.ui.control.Table
        ProgressAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetSizeButton
        function SetSizeButtonPushed(app, event)
             n = round(app.NField.Value);
    % A
    app.UITableA.ColumnName = repmat({''},1,n);
    app.UITableA.Data       = num2cell(zeros(n,n));
    % b
    app.UITableB.ColumnName = {'b'};
    app.UITableB.Data       = num2cell(zeros(n,1));
    % initial guess
    app.X0Field.Value       = repmat('0,',1,n);
    app.UITableX.Data       = {};
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
               A   = cell2mat(app.UITableA.Data);
    b   = cell2mat(app.UITableB.Data);
    x0  = sscanf(app.X0Field.Value, '%f,');
    eps   = app.EpsField.Value;
    itmax = app.IterField.Value;
    try
        x = seidelSolve(A, b, x0, eps, itmax);
        app.UITableX.Data = num2cell(x);
    catch ME
        uialert(app.UIFigure, ME.message, 'Eroare');
    end
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
               msg = [ ...
        "🔹 Metoda Gauss-Seidel rezolvă sisteme liniare de ecuații de forma A·x = b.", newline, newline, ...
        "🔸 Această metodă este o îmbunătățire a metodei Jacobi:", ...
        "  - Valorile noi calculate la pasul curent sunt folosite imediat în calculele următoare.", newline, newline, ...
        "🔸 Formula de calcul pentru fiecare componentă x(i):", ...
        "     xᵢ^(k+1) = (1 / aᵢᵢ) · (bᵢ - Σ(j ≠ i) aᵢⱼ · xⱼ)", newline, ...
        "     ➤ unde xⱼ este fie valoarea nouă (dacă j < i) sau veche (dacă j > i).", newline, newline, ...
        "🔹 Aplicația ta face următoarele:", ...
        "- Primește matricea A și vectorul b ca input.", ...
        "- Primește o valoare inițială x₀ (vector).", ...
        "- Primește ε (precizia acceptabilă) și numărul maxim de iterații.", newline, ...
        "- La fiecare pas calculează noul vector x⁽ᵏ⁺¹⁾ și diferența față de x⁽ᵏ⁾.", newline, newline, ...
        "🔸 Criteriul de oprire este: max|x⁽ᵏ⁺¹⁾ − x⁽ᵏ⁾| ≤ ε.", newline, ...
        "🔸 Tabelul afișează pas cu pas valorile lui x și erorile.", newline, ...
        "🔸 Graficul arată cum evoluează eroarea la fiecare pas.", newline, newline, ...
        "⚠️ ATENȚIE: Pentru a avea convergență, matricea A trebuie să fie:", ...
        "- Diagonal dominantă sau simetrică pozitiv definită.", newline ...
    ];
    
    uialert(app.UIFigure, strjoin(msg, newline), 'Despre metoda Gauss-Seidel');
        end

        % Button pushed function: ShowStepsButton
        function ShowStepsButtonPushed(app, event)
               % 1) Citește datele
        A   = cell2mat(app.UITableA.Data);
        b   = cell2mat(app.UITableB.Data);
        x0  = sscanf(app.X0Field.Value, '%f,');
        eps   = app.EpsField.Value;
        itmax = app.IterField.Value;

        % 2) Rulează Seidel detaliat
        updates = seidelProgressDataDetailed(A, b, x0, eps, itmax);
        % fiecare rând: {iter, idxComp, valoareVeche, formula, valoareNoua, diferenta}

        % 3) Populează tabelul cu antete prietenoase
        app.StepsTable.ColumnName = { ...
            'Iterație', 'Nod', 'Valoare veche', 'Formula calcul', ...
            'Valoare nouă', 'Diferență' };
        app.StepsTable.Data = updates;

        % 4) Plot evoluție diferență medie per iterație
        iters = unique(cell2mat(updates(:,1)))';
        meanDiffs = arrayfun(@(k) mean( ...
            cell2mat(updates(cell2mat(updates(:,1))==k,6)) ), iters);
        cla(app.ProgressAxes);
        plot(app.ProgressAxes, iters, meanDiffs, '-o', ...
             'LineWidth',1.5,'MarkerSize',6);
        xlabel(app.ProgressAxes,'Iterație');
        ylabel(app.ProgressAxes,'Diferență medie');
        title(app.ProgressAxes,'Evoluție diferență per iterație');
        grid(app.ProgressAxes,'on');
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgressAxes = uiaxes(app.UIFigure);
            title(app.ProgressAxes, 'Axa progres')
            xlabel(app.ProgressAxes, 'X')
            ylabel(app.ProgressAxes, 'Y')
            zlabel(app.ProgressAxes, 'Z')
            app.UITableA = uitable(app.UIFigure);
            app.UITableA.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.UITableB = uitable(app.UIFigure);
            app.UITableB.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tag = 'Info';
            app.InfoButton.Text = 'Info';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.NField = uieditfield(app.UIFigure, 'numeric');
            app.NField.Tag = 'NField';
            app.EpsFieldLabel = uilabel(app.UIFigure);
            app.EpsFieldLabel.Text = 'Eps Field';
            app.EpsField = uieditfield(app.UIFigure, 'numeric');
            app.EpsField.Tag = 'EpsField';
            app.IterFieldLabel = uilabel(app.UIFigure);
            app.IterFieldLabel.Text = 'IterField';
            app.IterField = uieditfield(app.UIFigure, 'numeric');
            app.IterField.Tag = 'IterField';
            app.SetSizeButton = uibutton(app.UIFigure, 'push');
            app.SetSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetSizeButtonPushed, true);
            app.SetSizeButton.Text = 'Dimensiune';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.StepsTable = uitable(app.UIFigure);
            app.StepsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StepsTable.RowName = {};
            app.ShowStepsButton = uibutton(app.UIFigure, 'push');
            app.ShowStepsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowStepsButtonPushed, true);
            app.ShowStepsButton.Text = 'Vizualizeaza';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0Field = uieditfield(app.UIFigure, 'text');
            app.X0Field.Tag = 'X0Field';
            app.UITableX = uitable(app.UIFigure);
            app.UITableX.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableX.RowName = {};
            app.UITableX.Tag = 'UITableX';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.MatriceatermenilorliberiLabel = uilabel(app.UIFigure);
            app.MatriceatermenilorliberiLabel.Text = 'Matricea termenilor liberi';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.PasidetaliatiLabel = uilabel(app.UIFigure);
            app.PasidetaliatiLabel.Text = 'Pasi detaliati';
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seidelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end