classdef succesiveAprox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ReturnButton_2       matlab.ui.control.Button
        MetodaaproximarilorsuccesiveLabel  matlab.ui.control.Label
        PasiDetaliatiLabel   matlab.ui.control.Label
        InfoButton           matlab.ui.control.Button
        ResultLabel          matlab.ui.control.Label
        AproxStepsTable      matlab.ui.control.Table
        VizStepsAproxButton  matlab.ui.control.Button
        SolveAproxButton     matlab.ui.control.Button
        IterField            matlab.ui.control.NumericEditField
        itmaxEditFieldLabel  matlab.ui.control.Label
        EpsField             matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        X0Field              matlab.ui.control.NumericEditField
        X0EditFieldLabel     matlab.ui.control.Label
        GEditField           matlab.ui.control.EditField
        gxLabel_2            matlab.ui.control.Label
        AproxAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        ReturnButton   matlab.ui.control.Button 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveAproxButton
        function SolveAproxButtonPushed(app, event)
           expr = app.GEditField.Value;

    try
        fstr = ['@(x) ' vectorize(expr)];
        g = str2func(fstr);
        x0 = app.X0Field.Value;
        testVal = g(x0);  % verificare sintaxă
    catch ME
        uialert(app.UIFigure, ...
            sprintf('Funcție g(x) invalidă sau x0 greșit:\n%s', ME.message), ...
            'Eroare');
        return;
    end

    eps = app.EpsField.Value;
    itmax = app.IterField.Value;

    try
        x = successiveSolve(g, x0, eps, itmax);

        % Afișează rezultatul în label
        app.ResultLabel.Text = sprintf("Soluție: x ≈ %.5f", x);

    catch ME
        app.ResultLabel.Text = "Eroare la calcul.";
        uialert(app.UIFigure, ME.message, 'Eroare Aproximări');
    end
        end

        % Button pushed function: VizStepsAproxButton
        function VizStepsAproxButtonPushed(app, event)
              % 1) Creează g(x) și testează
        expr = app.GEditField.Value;
        try
            fstr = ['@(x) ' vectorize(expr)];
            g = str2func(fstr);
            x0 = app.X0Field.Value;
            testVal = g(x0);
        catch ME
            uialert(app.UIFigure, ...
              sprintf('Funcție g(x) invalidă:\n%s', ME.message), ...
              'Eroare');
            return;
        end

        % 2) Parametrii
        eps   = app.EpsField.Value;
        itmax = app.IterField.Value;

      % 3) Colectează pașii detaliat
updates = successiveProgressDataDetailed(g, x0, eps, itmax);

% 4) Populează tabelul cu 5 coloane
app.AproxStepsTable.ColumnName = {'Iter','xₖ','g(xₖ)','|Δ|','Formula'};
app.AproxStepsTable.Data       = updates;

% 5) Ca să vezi și o imagine a erorii:
iters = cell2mat(updates(:,1));
diffs = cell2mat(updates(:,4));
cla(app.AproxAxes);
plot(app.AproxAxes, iters, diffs, '-o','LineWidth',1.5,'MarkerSize',6);
xlabel(app.AproxAxes,'Iterație');
ylabel(app.AproxAxes,'Diferență |xₖ₊₁ − xₖ|');
title(app.AproxAxes,'Evoluție diferență Aproximări Succ.');
grid(app.AproxAxes,'on');
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
               msg = [ ...
        "Metoda Aproximărilor Succesive rezolvă ecuații de forma f(x) = 0, ", ...
        "transformându-le în forma echivalentă x = g(x).", newline, newline, ...
        "Aplicația ta cere funcția g(x), o valoare inițială x₀,", ...
        "un număr maxim de iterații și o precizie ε (epsilon).", newline, newline, ...
        "Pasii sunt următorii:", ...
        "- La fiecare pas se calculează xₖ₊₁ = g(xₖ)", ...
        "- Se calculează diferența |xₖ₊₁ − xₖ|", ...
        "- Dacă diferența e mai mică decât ε, algoritmul se oprește.", newline, newline, ...
        "Condiția ca metoda să funcționeze bine este ca |g'(x)| < 1 în intervalul ales.", newline, newline, ...
        "După rulare, tabelul îți arată toți pașii făcuți.", ...
        "Graficul îți arată cum scade eroarea (|xₖ₊₁ − xₖ|) la fiecare pas." ...
    ];
    
    uialert(app.UIFigure, strjoin(msg, newline), 'Despre metodă și aplicație');
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AproxAxes = uiaxes(app.UIFigure);
            title(app.AproxAxes, 'Axa progres')
            xlabel(app.AproxAxes, 'X')
            ylabel(app.AproxAxes, 'Y')
            zlabel(app.AproxAxes, 'Z')
            app.gxLabel_2 = uilabel(app.UIFigure);
            app.gxLabel_2.Text = 'g(x) =';
            app.GEditField = uieditfield(app.UIFigure, 'text');
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0Field = uieditfield(app.UIFigure, 'numeric');
            app.X0Field.Tag = 'X0Field';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ε';
            app.EpsField = uieditfield(app.UIFigure, 'numeric');
            app.EpsField.Tag = 'EpsField';
            app.itmaxEditFieldLabel = uilabel(app.UIFigure);
            app.itmaxEditFieldLabel.Text = 'itmax';
            app.IterField = uieditfield(app.UIFigure, 'numeric');
            app.SolveAproxButton = uibutton(app.UIFigure, 'push');
            app.SolveAproxButton.ButtonPushedFcn = createCallbackFcn(app, @SolveAproxButtonPushed, true);
            app.SolveAproxButton.Text = 'Solve';
            app.VizStepsAproxButton = uibutton(app.UIFigure, 'push');
            app.VizStepsAproxButton.ButtonPushedFcn = createCallbackFcn(app, @VizStepsAproxButtonPushed, true);
            app.VizStepsAproxButton.Text = 'Vizualizeaza';
            app.AproxStepsTable = uitable(app.UIFigure);
            app.AproxStepsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AproxStepsTable.RowName = {};
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Tag = 'ResultLabel';
            app.ResultLabel.Text = '';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tag = 'InfoButton';
            app.InfoButton.Text = 'Info';
            app.PasiDetaliatiLabel = uilabel(app.UIFigure);
            app.PasiDetaliatiLabel.Text = 'Pasi Detaliati';
            app.MetodaaproximarilorsuccesiveLabel = uilabel(app.UIFigure);
            app.MetodaaproximarilorsuccesiveLabel.Text = 'Metoda aproximarilor succesive';
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = succesiveAprox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end