classdef trapezoidrRuleIntegralsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ReturnButton_2       matlab.ui.control.Button
        PasiidetaliatiLabel  matlab.ui.control.Label
        ResultLabel          matlab.ui.control.Label
        FunctionEditField    matlab.ui.control.EditField
        FunctionLabel        matlab.ui.control.Label
        StepsTable           matlab.ui.control.Table
        VisualiseButton      matlab.ui.control.Button
        SolveButton          matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        EpsEditField         matlab.ui.control.NumericEditField
        epsLabel             matlab.ui.control.Label
        EditFieldB           matlab.ui.control.NumericEditField
        bLabel               matlab.ui.control.Label
        EditFieldA           matlab.ui.control.NumericEditField
        aLabel               matlab.ui.control.Label
        TrapezoidRuleforApproximatingIntegralsLabel  matlab.ui.control.Label
        ConvergenceAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ReturnButton   matlab.ui.control.Button 
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
          
            try
                % 1) Preia parametri și f
        a   = app.EditFieldA.Value;
        b   = app.EditFieldB.Value;
        eps = app.EpsEditField.Value;
        expr = app.FunctionEditField.Value;
        f    = str2func(['@(x) ' expr]);

        % 2) Apelează trapezoidSolve
        [I, n] = trapezoidSolve(f, a, b, eps);

        % 3) Afișează în label
        app.ResultLabel.Text = sprintf( ...
          'I ≈ %.6f   (n = %d)', I, n);

    catch ME
        % dacă vrei, poți încă folosi uialert pentru erori
        uialert(app.UIFigure, ME.message, 'Error Solve');
            end


        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)

    msg = [ ...
      'Regula trapezelor aproxim. ∫_a^b f(x)dx cu pas h=(b-a)/n', newline, ...
      'I_n = (h/2)*(f(a) + 2 Σ_{i=1..n-1} f(a+i h) + f(b))', newline, ...
      'Se dublează n până când |I_n − I_{n/2}| ≤ ε', newline, ...
      'Pași afișați în tabel și graficul dublă-axis cu I și |ΔI|.' ];
    uialert(app.UIFigure, msg, 'Despre Trapezoidal Rule');


        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
        try
        % === 1. Parametrii de intrare ===
        a     = app.EditFieldA.Value;
        b     = app.EditFieldB.Value;
        eps   = app.EpsEditField.Value;
        expr  = app.FunctionEditField.Value;
        f     = str2func(['@(x) ' expr]);

        % === 2. Obține datele de progres pentru trapezoid ===
        data = trapezoidProgressData(f, a, b, eps);

        % === 3. Construim tabel matematic detaliat ===
        T = table( ...
            (1:length(data.n))', ...
            data.n(:), ...
            data.h(:), ...
            repmat(a, length(data.n), 1), ...
            repmat(b, length(data.n), 1), ...
            data.fx0(:), ...
            data.fxn(:), ...
            data.sumf(:), ...
            data.I(:), ...
            data.dI(:), ...
            'VariableNames', ...
            {'Pas', 'n', 'h', 'x₀', 'xₙ', 'f(x₀)', 'f(xₙ)', '∑f(xᵢ)', 'Iₙ', '|Iₙ - Iₙ₋₁|'} ...
        );

        % === 4. Afișează tabelul în UITable ===
        app.StepsTable.Data = T;
        app.StepsTable.ColumnName = T.Properties.VariableNames;

        % === 5. Plot avansat în ConvergenceAxes ===
        ax = app.ConvergenceAxes;
        cla(ax);
        yyaxis(ax, 'left')
            plot(ax, data.n, data.I, '-o', 'LineWidth', 1.8, 'MarkerFaceColor', 'b');
            ylabel(ax, 'Valoarea integrală Iₙ', 'FontWeight', 'bold');
        yyaxis(ax, 'right')
            semilogy(ax, data.n, data.dI, '-s', 'LineWidth', 1.8, 'MarkerFaceColor', 'r');
            ylabel(ax, 'Eroare |Iₙ - Iₙ₋₁| (log)', 'FontWeight', 'bold');

        xlabel(ax, 'Număr de diviziuni n', 'FontWeight', 'bold');
        title(ax, 'Convergența metodei trapezelor', 'FontSize', 14, 'FontWeight', 'bold');
        grid(ax, 'on');
        legend(ax, 'Iₙ', '|Iₙ - Iₙ₋₁|', 'Location', 'best');

    catch ME
        uialert(app.UIFigure, ['Eroare în vizualizare: ' newline ME.message], 'Eroare Visualise');
        end
        

        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
              delete(app.UIFigure);     % închide fereastra curentă
    LauncherApp();            % redeschide launcher‑ul principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvergenceAxes = uiaxes(app.UIFigure);
            title(app.ConvergenceAxes, 'Evolutie axa')
            xlabel(app.ConvergenceAxes, 'X')
            ylabel(app.ConvergenceAxes, 'Y')
            zlabel(app.ConvergenceAxes, 'Z')
            app.TrapezoidRuleforApproximatingIntegralsLabel = uilabel(app.UIFigure);
            app.TrapezoidRuleforApproximatingIntegralsLabel.Text = 'Trapezoid Rule for Approximating Integrals';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.EditFieldA = uieditfield(app.UIFigure, 'numeric');
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.EditFieldB = uieditfield(app.UIFigure, 'numeric');
            app.epsLabel = uilabel(app.UIFigure);
            app.epsLabel.Text = 'eps';
            app.EpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.StepsTable = uitable(app.UIFigure);
            app.StepsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StepsTable.RowName = {};
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            app.PasiidetaliatiLabel = uilabel(app.UIFigure);
            app.PasiidetaliatiLabel.Text = 'Pasii detaliati';
            app.ReturnButton_2 = uibutton(app.UIFigure, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'ReturnButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trapezoidrRuleIntegralsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end